classdef informacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IdiomaLanguageMenu              matlab.ui.container.Menu
        EnglishMenu                     matlab.ui.container.Menu
        EspaolMenu                      matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        SalirButton                     matlab.ui.control.Button
        Image2_2                        matlab.ui.control.Image
        InformacionGasificadoresButton  matlab.ui.control.Button
        CadenadeproducciondelabioenergiaLabel  matlab.ui.control.Label
        EnergiaelectricaButton          matlab.ui.control.Button
        EnergiatermicaButton            matlab.ui.control.Button
        GaseososButton                  matlab.ui.control.Button
        LiquidosButton                  matlab.ui.control.Button
        SolidosButton                   matlab.ui.control.Button
        SubproductosagricolasButton     matlab.ui.control.Button
        SubproductosanimalesButton      matlab.ui.control.Button
        ResiduossolidosurbanosRSUButton  matlab.ui.control.Button
        CultivosenergeticosButton       matlab.ui.control.Button
        ResiduosorganicosdeloshogaresButton  matlab.ui.control.Button
        ResiduoscomercialeseindustrialesButton  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Tab2                            matlab.ui.container.Tab
        Image2_3                        matlab.ui.control.Image
        ResiduoscomercialeseindustrialesLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        VolverButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        Image2_4                        matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        ResiduosorganicosdeloshogaresLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        VolverButton_2                  matlab.ui.control.Button
        Tab4                            matlab.ui.container.Tab
        Image2_5                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Label_3                         matlab.ui.control.Label
        CultivosenergeticosLabel        matlab.ui.control.Label
        VolverButton_3                  matlab.ui.control.Button
        Tab5                            matlab.ui.container.Tab
        Image2_6                        matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Label_4                         matlab.ui.control.Label
        ResiduossolidosurbanosRSULabel  matlab.ui.control.Label
        VolverButton_4                  matlab.ui.control.Button
        Tab6                            matlab.ui.container.Tab
        Image2_7                        matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Label_5                         matlab.ui.control.Label
        SubproductosagricolasLabel      matlab.ui.control.Label
        VolverButton_5                  matlab.ui.control.Button
        Tab7                            matlab.ui.container.Tab
        Image2_8                        matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Label_6                         matlab.ui.control.Label
        SubproductosanimalesLabel       matlab.ui.control.Label
        VolverButton_6                  matlab.ui.control.Button
        Tab8                            matlab.ui.container.Tab
        Image2_9                        matlab.ui.control.Image
        Label_7                         matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        BiocombustiblessolidosLabel_2   matlab.ui.control.Label
        VolverButton_7                  matlab.ui.control.Button
        Tab9                            matlab.ui.container.Tab
        Image2_10                       matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        Label_8                         matlab.ui.control.Label
        BiocombustiblesliquidosLabel    matlab.ui.control.Label
        VolverButton_8                  matlab.ui.control.Button
        Tab10                           matlab.ui.container.Tab
        Image2_11                       matlab.ui.control.Image
        Image10                         matlab.ui.control.Image
        Label_9                         matlab.ui.control.Label
        BiocombustiblesgaseososLabel    matlab.ui.control.Label
        VolverButton_9                  matlab.ui.control.Button
        Tab11                           matlab.ui.container.Tab
        Image2_12                       matlab.ui.control.Image
        Image11                         matlab.ui.control.Image
        Label_10                        matlab.ui.control.Label
        EnergiaelectricaLabel           matlab.ui.control.Label
        VolverButton_10                 matlab.ui.control.Button
        Tab12                           matlab.ui.container.Tab
        Image2_13                       matlab.ui.control.Image
        Image12                         matlab.ui.control.Image
        Label_11                        matlab.ui.control.Label
        EnergiatermicaLabel             matlab.ui.control.Label
        VolverButton_11                 matlab.ui.control.Button
        Tab13                           matlab.ui.container.Tab
        SalirButton_2                   matlab.ui.control.Button
        WillowLabel                     matlab.ui.control.Label
        Image2_14                       matlab.ui.control.Image
        InformacionbiodigestotresButton  matlab.ui.control.Button
        CarbonvegetalButton             matlab.ui.control.Button
        PinabeteButton                  matlab.ui.control.Button
        PinusstrobusButton              matlab.ui.control.Button
        PinosilvestreButton             matlab.ui.control.Button
        AbetorojoButton                 matlab.ui.control.Button
        SalixalbaButton                 matlab.ui.control.Button
        OlmoButton                      matlab.ui.control.Button
        CarpeButton                     matlab.ui.control.Button
        FresnoButton                    matlab.ui.control.Button
        AlnusincanaButton               matlab.ui.control.Button
        AlnusglutinosaButton            matlab.ui.control.Button
        QuercuscerrisButton             matlab.ui.control.Button
        RobleButton                     matlab.ui.control.Button
        HayaButton                      matlab.ui.control.Button
        AbedulButton                    matlab.ui.control.Button
        AcerpseudoplatanusButton        matlab.ui.control.Button
        AcerpseudoplatanusLabel         matlab.ui.control.Label
        Acerpseudoplatanus              matlab.ui.control.Image
        Tab14                           matlab.ui.container.Tab
        SalirButton_3                   matlab.ui.control.Button
        PixabayLabel                    matlab.ui.control.Label
        Image2_15                       matlab.ui.control.Image
        InformacionbiodigestotresButton_2  matlab.ui.control.Button
        CarbonvegetalButton_2           matlab.ui.control.Button
        PinabeteButton_2                matlab.ui.control.Button
        PinusstrobusButton_2            matlab.ui.control.Button
        PinosilvestreButton_2           matlab.ui.control.Button
        AbetorojoButton_2               matlab.ui.control.Button
        SalixalbaButton_2               matlab.ui.control.Button
        OlmoButton_2                    matlab.ui.control.Button
        CarpeButton_2                   matlab.ui.control.Button
        FresnoButton_2                  matlab.ui.control.Button
        AlnusincanaButton_2             matlab.ui.control.Button
        AlnusglutinosaButton_2          matlab.ui.control.Button
        QuercuscerrisButton_2           matlab.ui.control.Button
        RobleButton_2                   matlab.ui.control.Button
        HayaButton_2                    matlab.ui.control.Button
        AbedulButton_2                  matlab.ui.control.Button
        AcerpseudoplatanusButton_2      matlab.ui.control.Button
        Abedul                          matlab.ui.control.Image
        AbedulLabel                     matlab.ui.control.Label
        Tab15                           matlab.ui.container.Tab
        SalirButton_4                   matlab.ui.control.Button
        RosaMariaRinklLabel             matlab.ui.control.Label
        Image2_16                       matlab.ui.control.Image
        InformacionbiodigestotresButton_3  matlab.ui.control.Button
        CarbonvegetalButton_3           matlab.ui.control.Button
        PinabeteButton_3                matlab.ui.control.Button
        PinusstrobusButton_3            matlab.ui.control.Button
        PinosilvestreButton_3           matlab.ui.control.Button
        AbetorojoButton_3               matlab.ui.control.Button
        SalixalbaButton_3               matlab.ui.control.Button
        OlmoButton_3                    matlab.ui.control.Button
        CarpeButton_3                   matlab.ui.control.Button
        FresnoButton_3                  matlab.ui.control.Button
        AlnusincanaButton_3             matlab.ui.control.Button
        AlnusglutinosaButton_3          matlab.ui.control.Button
        QuercuscerrisButton_3           matlab.ui.control.Button
        RobleButton_3                   matlab.ui.control.Button
        HayaButton_3                    matlab.ui.control.Button
        AbedulButton_3                  matlab.ui.control.Button
        AcerpseudoplatanusButton_3      matlab.ui.control.Button
        Haya                            matlab.ui.control.Image
        HayaLabel                       matlab.ui.control.Label
        Tab16                           matlab.ui.container.Tab
        SalirButton_5                   matlab.ui.control.Button
        MaEugeniaMendiolaGonzlezLabel   matlab.ui.control.Label
        Image2_17                       matlab.ui.control.Image
        InformacionbiodigestotresButton_4  matlab.ui.control.Button
        CarbonvegetalButton_4           matlab.ui.control.Button
        PinabeteButton_4                matlab.ui.control.Button
        PinusstrobusButton_4            matlab.ui.control.Button
        PinosilvestreButton_4           matlab.ui.control.Button
        AbetorojoButton_4               matlab.ui.control.Button
        SalixalbaButton_4               matlab.ui.control.Button
        OlmoButton_4                    matlab.ui.control.Button
        CarpeButton_4                   matlab.ui.control.Button
        FresnoButton_4                  matlab.ui.control.Button
        AlnusincanaButton_4             matlab.ui.control.Button
        AlnusglutinosaButton_4          matlab.ui.control.Button
        QuercuscerrisButton_4           matlab.ui.control.Button
        RobleButton_4                   matlab.ui.control.Button
        HayaButton_4                    matlab.ui.control.Button
        AbedulButton_4                  matlab.ui.control.Button
        AcerpseudoplatanusButton_4      matlab.ui.control.Button
        Roble                           matlab.ui.control.Image
        RobleLabel                      matlab.ui.control.Label
        Tab17                           matlab.ui.container.Tab
        SalirButton_6                   matlab.ui.control.Button
        StephenJamesMcWilliamLabel      matlab.ui.control.Label
        Image2_18                       matlab.ui.control.Image
        InformacionbiodigestotresButton_5  matlab.ui.control.Button
        CarbonvegetalButton_5           matlab.ui.control.Button
        PinabeteButton_5                matlab.ui.control.Button
        PinusstrobusButton_5            matlab.ui.control.Button
        PinosilvestreButton_5           matlab.ui.control.Button
        AbetorojoButton_5               matlab.ui.control.Button
        SalixalbaButton_5               matlab.ui.control.Button
        OlmoButton_5                    matlab.ui.control.Button
        CarpeButton_5                   matlab.ui.control.Button
        FresnoButton_5                  matlab.ui.control.Button
        AlnusincanaButton_5             matlab.ui.control.Button
        AlnusglutinosaButton_5          matlab.ui.control.Button
        QuercuscerrisButton_5           matlab.ui.control.Button
        RobleButton_5                   matlab.ui.control.Button
        HayaButton_5                    matlab.ui.control.Button
        AbedulButton_5                  matlab.ui.control.Button
        AcerpseudoplatanusButton_5      matlab.ui.control.Button
        Roble_2                         matlab.ui.control.Image
        QuercuscerrisLabel              matlab.ui.control.Label
        Tab18                           matlab.ui.container.Tab
        SalirButton_7                   matlab.ui.control.Button
        AlexeyPSereginLabel             matlab.ui.control.Label
        Image2_19                       matlab.ui.control.Image
        InformacionbiodigestotresButton_6  matlab.ui.control.Button
        CarbonvegetalButton_6           matlab.ui.control.Button
        PinabeteButton_6                matlab.ui.control.Button
        PinusstrobusButton_6            matlab.ui.control.Button
        PinosilvestreButton_6           matlab.ui.control.Button
        AbetorojoButton_6               matlab.ui.control.Button
        SalixalbaButton_6               matlab.ui.control.Button
        OlmoButton_6                    matlab.ui.control.Button
        CarpeButton_6                   matlab.ui.control.Button
        FresnoButton_6                  matlab.ui.control.Button
        AlnusincanaButton_6             matlab.ui.control.Button
        AlnusglutinosaButton_6          matlab.ui.control.Button
        QuercuscerrisButton_6           matlab.ui.control.Button
        RobleButton_6                   matlab.ui.control.Button
        HayaButton_6                    matlab.ui.control.Button
        AbedulButton_6                  matlab.ui.control.Button
        AcerpseudoplatanusButton_6      matlab.ui.control.Button
        Roble_3                         matlab.ui.control.Image
        AlnusglutinosaLabel             matlab.ui.control.Label
        Tab19                           matlab.ui.container.Tab
        SalirButton_8                   matlab.ui.control.Button
        SteveFahmieLabel                matlab.ui.control.Label
        Image2_20                       matlab.ui.control.Image
        InformacionbiodigestotresButton_7  matlab.ui.control.Button
        CarbonvegetalButton_7           matlab.ui.control.Button
        PinabeteButton_7                matlab.ui.control.Button
        PinusstrobusButton_7            matlab.ui.control.Button
        PinosilvestreButton_7           matlab.ui.control.Button
        AbetorojoButton_7               matlab.ui.control.Button
        SalixalbaButton_7               matlab.ui.control.Button
        OlmoButton_7                    matlab.ui.control.Button
        CarpeButton_7                   matlab.ui.control.Button
        FresnoButton_7                  matlab.ui.control.Button
        AlnusincanaButton_7             matlab.ui.control.Button
        AlnusglutinosaButton_7          matlab.ui.control.Button
        QuercuscerrisButton_7           matlab.ui.control.Button
        RobleButton_7                   matlab.ui.control.Button
        HayaButton_7                    matlab.ui.control.Button
        AbedulButton_7                  matlab.ui.control.Button
        AcerpseudoplatanusButton_7      matlab.ui.control.Button
        Roble_4                         matlab.ui.control.Image
        AlnusincanaLabel                matlab.ui.control.Label
        Tab20                           matlab.ui.container.Tab
        SalirButton_9                   matlab.ui.control.Button
        AntonioSarabiaLabel             matlab.ui.control.Label
        Image2_21                       matlab.ui.control.Image
        InformacionbiodigestotresButton_8  matlab.ui.control.Button
        CarbonvegetalButton_8           matlab.ui.control.Button
        PinabeteButton_8                matlab.ui.control.Button
        PinusstrobusButton_8            matlab.ui.control.Button
        PinosilvestreButton_8           matlab.ui.control.Button
        AbetorojoButton_8               matlab.ui.control.Button
        SalixalbaButton_8               matlab.ui.control.Button
        OlmoButton_8                    matlab.ui.control.Button
        CarpeButton_8                   matlab.ui.control.Button
        FresnoButton_8                  matlab.ui.control.Button
        AlnusincanaButton_8             matlab.ui.control.Button
        AlnusglutinosaButton_8          matlab.ui.control.Button
        QuercuscerrisButton_8           matlab.ui.control.Button
        RobleButton_8                   matlab.ui.control.Button
        HayaButton_8                    matlab.ui.control.Button
        AbedulButton_8                  matlab.ui.control.Button
        AcerpseudoplatanusButton_8      matlab.ui.control.Button
        Roble_5                         matlab.ui.control.Image
        FresnoLabel                     matlab.ui.control.Label
        Tab21                           matlab.ui.container.Tab
        SalirButton_10                  matlab.ui.control.Button
        CarolHowardDonatiLabel          matlab.ui.control.Label
        Image2_22                       matlab.ui.control.Image
        InformacionbiodigestotresButton_9  matlab.ui.control.Button
        CarbonvegetalButton_9           matlab.ui.control.Button
        PinabeteButton_9                matlab.ui.control.Button
        PinusstrobusButton_9            matlab.ui.control.Button
        PinosilvestreButton_9           matlab.ui.control.Button
        AbetorojoButton_9               matlab.ui.control.Button
        SalixalbaButton_9               matlab.ui.control.Button
        OlmoButton_9                    matlab.ui.control.Button
        CarpeButton_9                   matlab.ui.control.Button
        FresnoButton_9                  matlab.ui.control.Button
        AlnusincanaButton_9             matlab.ui.control.Button
        AlnusglutinosaButton_9          matlab.ui.control.Button
        QuercuscerrisButton_9           matlab.ui.control.Button
        RobleButton_9                   matlab.ui.control.Button
        HayaButton_9                    matlab.ui.control.Button
        AbedulButton_9                  matlab.ui.control.Button
        AcerpseudoplatanusButton_9      matlab.ui.control.Button
        Roble_6                         matlab.ui.control.Image
        CarpeLabel                      matlab.ui.control.Label
        Tab22                           matlab.ui.container.Tab
        SalirButton_11                  matlab.ui.control.Button
        DouglasGoldmanLabel             matlab.ui.control.Label
        Image2_23                       matlab.ui.control.Image
        InformacionbiodigestotresButton_10  matlab.ui.control.Button
        CarbonvegetalButton_10          matlab.ui.control.Button
        PinabeteButton_10               matlab.ui.control.Button
        PinusstrobusButton_10           matlab.ui.control.Button
        PinosilvestreButton_10          matlab.ui.control.Button
        AbetorojoButton_10              matlab.ui.control.Button
        SalixalbaButton_10              matlab.ui.control.Button
        OlmoButton_10                   matlab.ui.control.Button
        CarpeButton_10                  matlab.ui.control.Button
        FresnoButton_10                 matlab.ui.control.Button
        AlnusincanaButton_10            matlab.ui.control.Button
        AlnusglutinosaButton_10         matlab.ui.control.Button
        QuercuscerrisButton_10          matlab.ui.control.Button
        RobleButton_10                  matlab.ui.control.Button
        HayaButton_10                   matlab.ui.control.Button
        AbedulButton_10                 matlab.ui.control.Button
        AcerpseudoplatanusButton_10     matlab.ui.control.Button
        Roble_7                         matlab.ui.control.Image
        OlmoLabel                       matlab.ui.control.Label
        Tab23                           matlab.ui.container.Tab
        SalirButton_12                  matlab.ui.control.Button
        MathildePayrastreLabel          matlab.ui.control.Label
        Image2_24                       matlab.ui.control.Image
        InformacionbiodigestotresButton_11  matlab.ui.control.Button
        CarbonvegetalButton_11          matlab.ui.control.Button
        PinabeteButton_11               matlab.ui.control.Button
        PinusstrobusButton_11           matlab.ui.control.Button
        PinosilvestreButton_11          matlab.ui.control.Button
        AbetorojoButton_11              matlab.ui.control.Button
        SalixalbaButton_11              matlab.ui.control.Button
        OlmoButton_11                   matlab.ui.control.Button
        CarpeButton_11                  matlab.ui.control.Button
        FresnoButton_11                 matlab.ui.control.Button
        AlnusincanaButton_11            matlab.ui.control.Button
        AlnusglutinosaButton_11         matlab.ui.control.Button
        QuercuscerrisButton_11          matlab.ui.control.Button
        RobleButton_11                  matlab.ui.control.Button
        HayaButton_11                   matlab.ui.control.Button
        AbedulButton_11                 matlab.ui.control.Button
        AcerpseudoplatanusButton_11     matlab.ui.control.Button
        Roble_8                         matlab.ui.control.Image
        SalixalbaLabel                  matlab.ui.control.Label
        Tab24                           matlab.ui.container.Tab
        SalirButton_13                  matlab.ui.control.Button
        kelwickLabel                    matlab.ui.control.Label
        Image2_25                       matlab.ui.control.Image
        InformacionbiodigestotresButton_12  matlab.ui.control.Button
        CarbonvegetalButton_12          matlab.ui.control.Button
        PinabeteButton_12               matlab.ui.control.Button
        PinusstrobusButton_12           matlab.ui.control.Button
        PinosilvestreButton_12          matlab.ui.control.Button
        AbetorojoButton_12              matlab.ui.control.Button
        SalixalbaButton_12              matlab.ui.control.Button
        OlmoButton_12                   matlab.ui.control.Button
        CarpeButton_12                  matlab.ui.control.Button
        FresnoButton_12                 matlab.ui.control.Button
        AlnusincanaButton_12            matlab.ui.control.Button
        AlnusglutinosaButton_12         matlab.ui.control.Button
        QuercuscerrisButton_12          matlab.ui.control.Button
        RobleButton_12                  matlab.ui.control.Button
        HayaButton_12                   matlab.ui.control.Button
        AbedulButton_12                 matlab.ui.control.Button
        AcerpseudoplatanusButton_12     matlab.ui.control.Button
        Roble_9                         matlab.ui.control.Image
        AbetorojoLabel                  matlab.ui.control.Label
        Tab25                           matlab.ui.container.Tab
        SalirButton_14                  matlab.ui.control.Button
        mustafagkmenLabel               matlab.ui.control.Label
        Image2_26                       matlab.ui.control.Image
        InformacionbiodigestotresButton_13  matlab.ui.control.Button
        CarbonvegetalButton_13          matlab.ui.control.Button
        PinabeteButton_13               matlab.ui.control.Button
        PinusstrobusButton_13           matlab.ui.control.Button
        PinosilvestreButton_13          matlab.ui.control.Button
        AbetorojoButton_13              matlab.ui.control.Button
        SalixalbaButton_13              matlab.ui.control.Button
        OlmoButton_13                   matlab.ui.control.Button
        CarpeButton_13                  matlab.ui.control.Button
        FresnoButton_13                 matlab.ui.control.Button
        AlnusincanaButton_13            matlab.ui.control.Button
        AlnusglutinosaButton_13         matlab.ui.control.Button
        QuercuscerrisButton_13          matlab.ui.control.Button
        RobleButton_13                  matlab.ui.control.Button
        HayaButton_13                   matlab.ui.control.Button
        AbedulButton_13                 matlab.ui.control.Button
        AcerpseudoplatanusButton_13     matlab.ui.control.Button
        Roble_10                        matlab.ui.control.Image
        PinosilvestreLabel              matlab.ui.control.Label
        Tab26                           matlab.ui.container.Tab
        SalirButton_15                  matlab.ui.control.Button
        coppodoLabel                    matlab.ui.control.Label
        Image2_27                       matlab.ui.control.Image
        InformacionbiodigestotresButton_14  matlab.ui.control.Button
        CarbonvegetalButton_14          matlab.ui.control.Button
        PinabeteButton_14               matlab.ui.control.Button
        PinusstrobusButton_14           matlab.ui.control.Button
        PinosilvestreButton_14          matlab.ui.control.Button
        AbetorojoButton_14              matlab.ui.control.Button
        SalixalbaButton_14              matlab.ui.control.Button
        OlmoButton_14                   matlab.ui.control.Button
        CarpeButton_14                  matlab.ui.control.Button
        FresnoButton_14                 matlab.ui.control.Button
        AlnusincanaButton_14            matlab.ui.control.Button
        AlnusglutinosaButton_14         matlab.ui.control.Button
        QuercuscerrisButton_14          matlab.ui.control.Button
        RobleButton_14                  matlab.ui.control.Button
        HayaButton_14                   matlab.ui.control.Button
        AbedulButton_14                 matlab.ui.control.Button
        AcerpseudoplatanusButton_14     matlab.ui.control.Button
        Roble_11                        matlab.ui.control.Image
        PinusstrobusLabel               matlab.ui.control.Label
        Tab27                           matlab.ui.container.Tab
        SalirButton_16                  matlab.ui.control.Button
        MaleenMundLabel                 matlab.ui.control.Label
        Image2_28                       matlab.ui.control.Image
        InformacionbiodigestotresButton_15  matlab.ui.control.Button
        CarbonvegetalButton_15          matlab.ui.control.Button
        PinabeteButton_15               matlab.ui.control.Button
        PinusstrobusButton_15           matlab.ui.control.Button
        PinosilvestreButton_15          matlab.ui.control.Button
        AbetorojoButton_15              matlab.ui.control.Button
        SalixalbaButton_15              matlab.ui.control.Button
        OlmoButton_15                   matlab.ui.control.Button
        CarpeButton_15                  matlab.ui.control.Button
        FresnoButton_15                 matlab.ui.control.Button
        AlnusincanaButton_15            matlab.ui.control.Button
        AlnusglutinosaButton_15         matlab.ui.control.Button
        QuercuscerrisButton_15          matlab.ui.control.Button
        RobleButton_15                  matlab.ui.control.Button
        HayaButton_15                   matlab.ui.control.Button
        AbedulButton_15                 matlab.ui.control.Button
        AcerpseudoplatanusButton_15     matlab.ui.control.Button
        Roble_12                        matlab.ui.control.Image
        PinabeteLabel                   matlab.ui.control.Label
        Tab28                           matlab.ui.container.Tab
        SalirButton_17                  matlab.ui.control.Button
        STRONGlk7Label                  matlab.ui.control.Label
        Image2_29                       matlab.ui.control.Image
        InformacionbiodigestotresButton_16  matlab.ui.control.Button
        CarbonvegetalButton_16          matlab.ui.control.Button
        PinabeteButton_16               matlab.ui.control.Button
        PinusstrobusButton_16           matlab.ui.control.Button
        PinosilvestreButton_16          matlab.ui.control.Button
        AbetorojoButton_16              matlab.ui.control.Button
        SalixalbaButton_16              matlab.ui.control.Button
        OlmoButton_16                   matlab.ui.control.Button
        CarpeButton_16                  matlab.ui.control.Button
        FresnoButton_16                 matlab.ui.control.Button
        AlnusincanaButton_16            matlab.ui.control.Button
        AlnusglutinosaButton_16         matlab.ui.control.Button
        QuercuscerrisButton_16          matlab.ui.control.Button
        RobleButton_16                  matlab.ui.control.Button
        HayaButton_16                   matlab.ui.control.Button
        AbedulButton_16                 matlab.ui.control.Button
        AcerpseudoplatanusButton_16     matlab.ui.control.Button
        Roble_13                        matlab.ui.control.Image
        CarbonvegetalLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
         idioma
         Inicioapp
         Instructivo2app;
        Proceso_Biodigestorapp;
        Proceso_Gasificadorapp;
        
    end
    
    methods (Access = private)
        
        function guardaridiomas(app,idioma)

            app.SalirButton.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_2.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_3.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_4.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_5.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_6.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_7.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_8.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_9.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_10.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_11.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_12.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_13.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_14.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_15.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_16.Text = buscador(app,idioma, 'botonSalir1');
            app.SalirButton_17.Text = buscador(app,idioma, 'botonSalir1');

            app.InformacionGasificadoresButton.Text = buscador(app, idioma, 'botonInformacionGasificadores');
            app.CadenadeproducciondelabioenergiaLabel.Text = buscador(app, idioma, 'tituloCadenaProduccion');
            app.ResiduoscomercialeseindustrialesButton.Text = buscador(app, idioma, 'botonResiduosComerciales');
            app.ResiduosorganicosdeloshogaresButton.Text = buscador(app, idioma, 'botonResiduosOrganicos');
            app.CultivosenergeticosButton.Text = buscador(app, idioma, 'botonCultivosEnergeticos');
            app.ResiduossolidosurbanosRSUButton.Text = buscador(app, idioma, 'botonResiduosSolidos');
            app.SubproductosagricolasButton.Text = buscador(app, idioma, 'botonSubproductosAgricolas');
            app.SubproductosanimalesButton.Text = buscador(app, idioma, 'botonSubproductosAnimales');
            app.SolidosButton.Text = buscador(app, idioma, 'botonSolidos');
            app.LiquidosButton.Text = buscador(app, idioma, 'botonLiquidos');
            app.GaseososButton.Text = buscador(app, idioma, 'botonGaseosos');
            app.EnergiaelectricaButton.Text = buscador(app, idioma, 'botonEnergiaElectrica');
            app.EnergiatermicaButton.Text = buscador(app, idioma, 'botonEnergiaTermica');
           
            % Tabs info biodigestores

            app.ResiduoscomercialeseindustrialesLabel.Text = buscador(app, idioma, 'tituloResiduosComerciales'); 
            app.Label.Text = buscador(app, idioma, 'labelResiduosComerciales');
            app.ResiduosorganicosdeloshogaresLabel.Text = buscador(app, idioma, 'tituloResiduosOrganicos');
            app.Label_2.Text = buscador(app, idioma, 'labelResiduosOrganicos');
            app.CultivosenergeticosLabel.Text = buscador(app, idioma, 'tituloCultivosEnergeticos');
            app.Label_3.Text = buscador(app, idioma, 'labelCultivosEnergeticos');
            app.ResiduossolidosurbanosRSULabel.Text = buscador(app, idioma, 'tituloResiduosSolidos');
            app.Label_4.Text = buscador(app, idioma, 'labelResiduosSolidos');
            app.SubproductosagricolasLabel.Text = buscador(app, idioma, 'tituloSubproductosAgricolas');
            app.Label_5.Text = buscador(app, idioma, 'labelSubproductosAgricolas');
            app.SubproductosanimalesLabel.Text = buscador(app, idioma, 'tituloSubproductosAnimales');
            app.Label_6.Text = buscador(app, idioma, 'labelSubproductosAnimales');
            app.BiocombustiblessolidosLabel_2.Text = buscador(app, idioma, 'tituloSolidos');
            app.Label_7.Text = buscador(app, idioma, 'labelSolidos');
            app.BiocombustiblesliquidosLabel.Text = buscador(app, idioma, 'tituloLiquidos');
            app.Label_8.Text = buscador(app, idioma, 'labelLiquidos');
            app.BiocombustiblesgaseososLabel.Text = buscador(app, idioma, 'tituloGaseosos');
            app.Label_9.Text = buscador(app, idioma, 'labelGaseosos');
            app.EnergiaelectricaLabel.Text = buscador(app, idioma, 'tituloEnergiaElectrica');
            app.Label_10.Text = buscador(app, idioma, 'labelEnergiaElectrica');
            app.EnergiatermicaLabel.Text = buscador(app, idioma, 'tituloEnergiaTermica');
            app.Label_11.Text = buscador(app, idioma, 'labelEnergiaTermica');
            app.VolverButton.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_2.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_3.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_4.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_5.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_6.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_7.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_8.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_9.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_10.Text = buscador(app, idioma, 'botonVolver');
            app.VolverButton_11.Text = buscador(app, idioma, 'botonVolver');
            
            % Tabs info gasificadores

            app.InformacionbiodigestotresButton.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_2.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_3.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_4.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_5.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_6.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_7.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_8.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_9.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_10.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_11.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_12.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_13.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_14.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_15.Text = buscador(app, idioma, 'botonInformacionBiodigestores');
            app.InformacionbiodigestotresButton_16.Text = buscador(app, idioma, 'botonInformacionBiodigestores');

            app.CarbonvegetalButton.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_2.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_3.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_4.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_5.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_6.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_7.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_8.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_9.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_10.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_11.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_12.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_13.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_14.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_15.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalButton_16.Text = buscador(app, idioma, 'botonCarbonVegetal');
            app.CarbonvegetalLabel.Text = buscador(app, idioma, 'botonCarbonVegetal');


        end
        
        
        function  Idioma = buscador(app, idioma, texto)
            
            
            switch idioma
                case 'Español'
                    Idioma = Spanish.(texto);
                    
                case 'English'
                    Idioma = English.(texto);
                
            end
               

        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inicioapp, idioma)
            app.idioma = 'English';
            if app.idioma == "Español"
                app.EspaolMenu.Checked = "on";
               app.EnglishMenu.Checked = "off";
                guardaridiomas(app, idioma);
            end
            if app.idioma == "English"
                app.EspaolMenu.Checked = "off";
                app.EnglishMenu.Checked = "on";
                guardaridiomas(app, idioma);
            end

        end

        % Button pushed function: ResiduoscomercialeseindustrialesButton
        function ResiduoscomercialeseindustrialesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab2;
        end

        % Button pushed function: ResiduosorganicosdeloshogaresButton
        function ResiduosorganicosdeloshogaresButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab3;
        end

        % Button pushed function: CultivosenergeticosButton
        function CultivosenergeticosButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab4;
        end

        % Button pushed function: ResiduossolidosurbanosRSUButton
        function ResiduossolidosurbanosRSUButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab5;
        end

        % Button pushed function: SubproductosagricolasButton
        function SubproductosagricolasButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab6;
        end

        % Button pushed function: SubproductosanimalesButton
        function SubproductosanimalesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab7;
        end

        % Button pushed function: SolidosButton
        function SolidosButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab8;
        end

        % Button pushed function: LiquidosButton
        function LiquidosButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab9;
        end

        % Button pushed function: GaseososButton
        function GaseososButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab10;
        end

        % Button pushed function: EnergiaelectricaButton
        function EnergiaelectricaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab11;
        end

        % Button pushed function: EnergiatermicaButton
        function EnergiatermicaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab12;
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_2
        function VolverButton_2Pushed(app, event)
             app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_3
        function VolverButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_4
        function VolverButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_5
        function VolverButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_6
        function VolverButton_6Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_7
        function VolverButton_7Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_8
        function VolverButton_8Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_9
        function VolverButton_9Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_10
        function VolverButton_10Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Button pushed function: VolverButton_11
        function VolverButton_11Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Callback function
        function AcerpseudoplatanusButtonPushed(app, event)
           
        end

        % Button pushed function: InformacionGasificadoresButton
        function InformacionGasificadoresButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab13;
             
        end

        % Callback function
        function AbedulButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.Tab13;app.Abedul.Visible="on"
            app.TabGroup.SelectedTab=app.Tab13;app.Abedul.Enable="on"
        end

        % Callback function
        function AcerpseudoplatanusCheckBoxValueChanged(app, event)
    
        end

        % Callback function
        function AbedulCheckBoxValueChanged(app, event)

        end

        % Callback function
        function HayaCheckBoxValueChanged(app, event)
      
        end

        % Callback function
        function RobleCheckBoxValueChanged(app, event)
 
        end

        % Button pushed function: AcerpseudoplatanusButton, 
        % ...and 15 other components
        function AcerpseudoplatanusButtonPushed2(app, event)
                 app.TabGroup.SelectedTab = app.Tab13;

        end

        % Button pushed function: AbedulButton, AbedulButton_10, 
        % ...and 14 other components
        function AbedulButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.Tab14;
        end

        % Button pushed function: HayaButton, HayaButton_10, 
        % ...and 14 other components
        function HayaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab15;
        end

        % Button pushed function: RobleButton, RobleButton_10, 
        % ...and 14 other components
        function RobleButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab16;
        end

        % Button pushed function: QuercuscerrisButton, 
        % ...and 15 other components
        function QuercuscerrisButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab17;
        end

        % Button pushed function: AlnusglutinosaButton, 
        % ...and 15 other components
        function AlnusglutinosaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab18;
        end

        % Button pushed function: AlnusincanaButton, AlnusincanaButton_10, 
        % ...and 14 other components
        function AlnusincanaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab19;
        end

        % Button pushed function: FresnoButton, FresnoButton_10, 
        % ...and 14 other components
        function FresnoButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab20;
        end

        % Button pushed function: CarpeButton, CarpeButton_10, 
        % ...and 14 other components
        function CarpeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab21;
        end

        % Button pushed function: OlmoButton, OlmoButton_10, 
        % ...and 14 other components
        function OlmoButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab22;
        end

        % Button pushed function: SalixalbaButton, SalixalbaButton_10, 
        % ...and 14 other components
        function SalixalbaButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab23;
        end

        % Button pushed function: AbetorojoButton, AbetorojoButton_10, 
        % ...and 14 other components
        function AbetorojoButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab24;
        end

        % Button pushed function: PinosilvestreButton, 
        % ...and 15 other components
        function PinosilvestreButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab25;
        end

        % Button pushed function: PinusstrobusButton, 
        % ...and 15 other components
        function PinusstrobusButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab26;
        end

        % Button pushed function: PinabeteButton, PinabeteButton_10, 
        % ...and 14 other components
        function PinabeteButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab27;
        end

        % Button pushed function: CarbonvegetalButton, 
        % ...and 15 other components
        function CarbonvegetalButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab28;
        end

        % Button pushed function: InformacionbiodigestotresButton, 
        % ...and 15 other components
        function InformacionbiodigestotresButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab;
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            app.idioma = "English";
            guardaridiomas(app, 'English');
            app.EspaolMenu.Checked = "off";
            app.EnglishMenu.Checked = "on";    

        end

        % Menu selected function: EspaolMenu
        function EspaolMenuSelected(app, event)
            app.idioma = "Español";
            guardaridiomas(app, 'Español');
            app.EnglishMenu.Checked = "off";
            app.EspaolMenu.Checked = "on";

        end

        % Button pushed function: SalirButton, SalirButton_10, 
        % ...and 15 other components
        function salirboton(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IdiomaLanguageMenu = uimenu(app.UIFigure);
            app.IdiomaLanguageMenu.Text = '»» Idioma/Language';
            app.EnglishMenu = uimenu(app.IdiomaLanguageMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.EspaolMenu = uimenu(app.IdiomaLanguageMenu);
            app.EspaolMenu.MenuSelectedFcn = createCallbackFcn(app, @EspaolMenuSelected, true);
            app.EspaolMenu.Text = 'Español';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Residuos comerciales e industriales.jpeg');
            app.ResiduoscomercialeseindustrialesButton = uibutton(app.Tab, 'push');
            app.ResiduoscomercialeseindustrialesButton.ButtonPushedFcn = createCallbackFcn(app, @ResiduoscomercialeseindustrialesButtonPushed, true);
            app.ResiduoscomercialeseindustrialesButton.Text = {'Residuos comerciales'; ' e industriales'};
            app.ResiduosorganicosdeloshogaresButton = uibutton(app.Tab, 'push');
            app.ResiduosorganicosdeloshogaresButton.ButtonPushedFcn = createCallbackFcn(app, @ResiduosorganicosdeloshogaresButtonPushed, true);
            app.ResiduosorganicosdeloshogaresButton.Text = {'Residuos organicos '; 'de los hogares'};
            app.CultivosenergeticosButton = uibutton(app.Tab, 'push');
            app.CultivosenergeticosButton.ButtonPushedFcn = createCallbackFcn(app, @CultivosenergeticosButtonPushed, true);
            app.CultivosenergeticosButton.Text = 'Cultivos energeticos';
            app.ResiduossolidosurbanosRSUButton = uibutton(app.Tab, 'push');
            app.ResiduossolidosurbanosRSUButton.ButtonPushedFcn = createCallbackFcn(app, @ResiduossolidosurbanosRSUButtonPushed, true);
            app.ResiduossolidosurbanosRSUButton.Text = {'Residuos solidos'; 'urbanos (RSU)'};
            app.SubproductosanimalesButton = uibutton(app.Tab, 'push');
            app.SubproductosanimalesButton.ButtonPushedFcn = createCallbackFcn(app, @SubproductosanimalesButtonPushed, true);
            app.SubproductosanimalesButton.Text = {'Subproductos'; 'animales'};
            app.SubproductosagricolasButton = uibutton(app.Tab, 'push');
            app.SubproductosagricolasButton.ButtonPushedFcn = createCallbackFcn(app, @SubproductosagricolasButtonPushed, true);
            app.SubproductosagricolasButton.Text = {'Subproductos'; 'agricolas'};
            app.SolidosButton = uibutton(app.Tab, 'push');
            app.SolidosButton.ButtonPushedFcn = createCallbackFcn(app, @SolidosButtonPushed, true);
            app.SolidosButton.Text = 'Solidos';
            app.LiquidosButton = uibutton(app.Tab, 'push');
            app.LiquidosButton.ButtonPushedFcn = createCallbackFcn(app, @LiquidosButtonPushed, true);
            app.LiquidosButton.Text = 'Liquidos';
            app.GaseososButton = uibutton(app.Tab, 'push');
            app.GaseososButton.ButtonPushedFcn = createCallbackFcn(app, @GaseososButtonPushed, true);
            app.GaseososButton.Text = 'Gaseosos';
            app.EnergiatermicaButton = uibutton(app.Tab, 'push');
            app.EnergiatermicaButton.ButtonPushedFcn = createCallbackFcn(app, @EnergiatermicaButtonPushed, true);
            app.EnergiatermicaButton.Text = 'Energia termica';
            app.EnergiaelectricaButton = uibutton(app.Tab, 'push');
            app.EnergiaelectricaButton.ButtonPushedFcn = createCallbackFcn(app, @EnergiaelectricaButtonPushed, true);
            app.EnergiaelectricaButton.Text = 'Energia electrica';
            app.CadenadeproducciondelabioenergiaLabel = uilabel(app.Tab);
            app.CadenadeproducciondelabioenergiaLabel.Text = 'Cadena de produccion de la bioenergia';
            app.InformacionGasificadoresButton = uibutton(app.Tab, 'push');
            app.InformacionGasificadoresButton.ButtonPushedFcn = createCallbackFcn(app, @InformacionGasificadoresButtonPushed, true);
            app.InformacionGasificadoresButton.Text = {'Informacion '; 'Gasificadores'};
            app.Image2_2 = uiimage(app.Tab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.SalirButton = uibutton(app.Tab, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton.Text = 'Salir';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Label = uilabel(app.Tab2);
            app.Label.Text = {'Los desechos industriales son aquellos generados por la actividad propia del comercio o la industria, '; 'al por mayor o al por menor, va dirigido a cualquier generador de residuos sólidos urbanos no peligrosos. '; 'Los desechos industriales más comunes son las mezclas de materia orgánica, envases ligeros, papel, '; 'cartón, textil y el resto de residuos industriales en general.'};
            app.VolverButton = uibutton(app.Tab2, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.Image2 = uiimage(app.Tab2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'fundas-banano-solidaridad.jpg');
            app.ResiduoscomercialeseindustrialesLabel = uilabel(app.Tab2);
            app.ResiduoscomercialeseindustrialesLabel.Text = 'Residuos comerciales e industriales';
            app.Image2_3 = uiimage(app.Tab2);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.VolverButton_2 = uibutton(app.Tab3, 'push');
            app.VolverButton_2.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_2Pushed, true);
            app.VolverButton_2.Text = 'Volver';
            app.Label_2 = uilabel(app.Tab3);
            app.Label_2.Text = {'Son los residuos orgánicos biodegradables'; ' de origen vegetal  (restos de frutas y verduras,'; ' incluidas las cascaras) y/o animal (huesos, restos'; ' de carne, espinas y toda clase de resto de pescado)'; ' como los restos de comida, restos de jardinería'; ' y podas.'};
            app.ResiduosorganicosdeloshogaresLabel = uilabel(app.Tab3);
            app.ResiduosorganicosdeloshogaresLabel.Text = 'Residuos organicos de los hogares';
            app.Image3 = uiimage(app.Tab3);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'AgenciaNoticias-20161003-01.jpg');
            app.Image2_4 = uiimage(app.Tab3);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.VolverButton_3 = uibutton(app.Tab4, 'push');
            app.VolverButton_3.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_3Pushed, true);
            app.VolverButton_3.Text = 'Volver';
            app.CultivosenergeticosLabel = uilabel(app.Tab4);
            app.CultivosenergeticosLabel.Text = 'Cultivos energeticos';
            app.Label_3 = uilabel(app.Tab4);
            app.Label_3.Text = {'Los cultivos energéticos son una categoría'; ' muy amplia, que engloba a todas aquellas '; 'especies vegetales cuya producción principal'; ' se destina a la producción de biomasa con la'; ' que generar energía térmica o eléctrica o'; ' producir biocombustibles.'};
            app.Image4 = uiimage(app.Tab4);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'bioetanol-696x397.jpg');
            app.Image2_5 = uiimage(app.Tab4);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.VolverButton_4 = uibutton(app.Tab5, 'push');
            app.VolverButton_4.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_4Pushed, true);
            app.VolverButton_4.Text = 'Volver';
            app.ResiduossolidosurbanosRSULabel = uilabel(app.Tab5);
            app.ResiduossolidosurbanosRSULabel.Text = 'Residuos solidos urbanos(RSU)';
            app.Label_4 = uilabel(app.Tab5);
            app.Label_4.Text = {'Un residuo es cualquier material que resulta de un proceso de fabricación, transformación, uso, consumo'; ' o limpieza, que se abandona porque carece de valor o utilidad para el propietario. Los residuos urbanos '; 'son aquellos que se generan en los domicilios particulares, comercios, oficinas y servicios, así como todos'; ' los que sean de la misma naturaleza que éstos (limpieza de vías públicas, zonas verdes, áreas recreativas, '; 'animales domésticos muertos, muebles y enseres, escombros de obras menores y reparación domiciliaria)'};
            app.Image5 = uiimage(app.Tab5);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'tratamiento-residuos-solidos-urbanos.jpg');
            app.Image2_6 = uiimage(app.Tab5);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.VolverButton_5 = uibutton(app.Tab6, 'push');
            app.VolverButton_5.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_5Pushed, true);
            app.VolverButton_5.Text = 'Volver';
            app.SubproductosagricolasLabel = uilabel(app.Tab6);
            app.SubproductosagricolasLabel.Text = 'Subproductos agricolas';
            app.Label_5 = uilabel(app.Tab6);
            app.Label_5.Text = {'La biomasa residual hace referencia a los subproductos que se derivan de las transformaciones naturales'; ' o industriales que llevan a cabo la materia orgánica, por ejemplo, los residuos de cosecha, las podas'; ' verdes de los cultivos, los residuos organicos de los procesos de agricultura y los residuos orgánicos'; ' de las plazas de mercado entre otros'};
            app.Image6 = uiimage(app.Tab6);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'agricola_bioma.png');
            app.Image2_7 = uiimage(app.Tab6);
            app.Image2_7.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = 'Tab7';
            app.VolverButton_6 = uibutton(app.Tab7, 'push');
            app.VolverButton_6.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_6Pushed, true);
            app.VolverButton_6.Text = 'Volver';
            app.SubproductosanimalesLabel = uilabel(app.Tab7);
            app.SubproductosanimalesLabel.Text = 'Subproductos animales';
            app.Label_6 = uilabel(app.Tab7);
            app.Label_6.Text = {'La bimasa pecuaria incluye todo residuo biodegradable procedente de la actividad ganadera, y se puede clasificar '; 'en estiércol, compuesto por la mezcla de las excretas y el material de la cama del ganado; mezcla de estiercol'; ' y el agua de limpieza y arrastre; aguas sucias procedentes del lavado, desperdicios de abrevaderos'; ' y animales muertos'};
            app.Image7 = uiimage(app.Tab7);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'easset_upload_file56434_909724_e.jpg');
            app.Image2_8 = uiimage(app.Tab7);
            app.Image2_8.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab8 = uitab(app.TabGroup);
            app.Tab8.Title = 'Tab8';
            app.VolverButton_7 = uibutton(app.Tab8, 'push');
            app.VolverButton_7.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_7Pushed, true);
            app.VolverButton_7.Text = 'Volver';
            app.BiocombustiblessolidosLabel_2 = uilabel(app.Tab8);
            app.BiocombustiblessolidosLabel_2.Text = 'Biocombustibles solidos';
            app.Image8 = uiimage(app.Tab8);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'biomasa_0.gif');
            app.Label_7 = uilabel(app.Tab8);
            app.Label_7.Text = {'Los biocombustibles sólidos, englobados'; ' en el concepto de la biomasa, son una fuente'; ' de energía producida y generada a partir de'; ' restos de productos forestales y vegetales.'; ' Se trata de residuos de materia orgánica'; ' y natural de gran poder calorífico y energético'; ' donde podemos incluir residuos provenientes'; ' de la actividad agrícola, industrial y desechos'; ' originados de la gestión forestal de los '; 'bosques.'};
            app.Image2_9 = uiimage(app.Tab8);
            app.Image2_9.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab9 = uitab(app.TabGroup);
            app.Tab9.Title = 'Tab9';
            app.VolverButton_8 = uibutton(app.Tab9, 'push');
            app.VolverButton_8.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_8Pushed, true);
            app.VolverButton_8.Text = 'Volver';
            app.BiocombustiblesliquidosLabel = uilabel(app.Tab9);
            app.BiocombustiblesliquidosLabel.Text = 'Biocombustibles liquidos';
            app.Label_8 = uilabel(app.Tab9);
            app.Label_8.Text = {'Los biocombustibles son los combustibles que se producen, directa o indirectamente, a partir de recursos naturales'; ' y la biomasa.La biomasa, por su parte, es la fuente de energía que proviene de materiales no fósiles y de '; 'origen biológico, como pueden ser los cultivos energéticos, los desechos agrícolas y forestales y sus '; 'subproductos (el estiércol o la biomasa microbiana). En la actualidad, son comunes los biocombustibles'; ' que proceden del azúcar, del maíz, del trigo o de las semillas oleaginosas, entre otros.(etanol, el biodiésel y'; ' los aceites pirolíticos)'};
            app.Image9 = uiimage(app.Tab9);
            app.Image9.ImageSource = fullfile(pathToMLAPP, '62ab939f02e72.jpeg');
            app.Image2_10 = uiimage(app.Tab9);
            app.Image2_10.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab10 = uitab(app.TabGroup);
            app.Tab10.Title = 'Tab10';
            app.VolverButton_9 = uibutton(app.Tab10, 'push');
            app.VolverButton_9.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_9Pushed, true);
            app.VolverButton_9.Text = 'Volver';
            app.BiocombustiblesgaseososLabel = uilabel(app.Tab10);
            app.BiocombustiblesgaseososLabel.Text = 'Biocombustibles gaseosos';
            app.Label_9 = uilabel(app.Tab10);
            app.Label_9.Text = {'Los biocombustibles gaseosos se obtienen mediante'; 'procesos térmicos (como la pirólisis), microbianos'; '(descomposición de materia orgánica en ausencia'; 'de oxígeno), o de gasificación. El biohidrógeno y'; 'el metano son los biocombustibles gaseosos más'; 'conocidos.'; 'La pirólisis es un proceso térmico realizado a altas'; 'temperaturas en condiciones anaeróbicas '; '(en ausencia de aire), con atmósferas inertes,'; ' como helio o nitrógeno'};
            app.Image10 = uiimage(app.Tab10);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'proceso-biodigestor-768x451.png');
            app.Image2_11 = uiimage(app.Tab10);
            app.Image2_11.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab11 = uitab(app.TabGroup);
            app.Tab11.Title = 'Tab11';
            app.VolverButton_10 = uibutton(app.Tab11, 'push');
            app.VolverButton_10.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_10Pushed, true);
            app.VolverButton_10.Text = 'Volver';
            app.EnergiaelectricaLabel = uilabel(app.Tab11);
            app.EnergiaelectricaLabel.Text = 'Energia electrica';
            app.Label_10 = uilabel(app.Tab11);
            app.Label_10.Text = {'El biogás generado en grandes cantidades puede ser empleado como combustible para alimentar un'; ' generador que produzca electricidad.Estos generadores estan diseñados especialemente para utilizar'; ' el gas (metano) como combustible y acoplados a generadores que la convierten en  la electricidad '; 'para ser utilizada por el usuario'};
            app.Image11 = uiimage(app.Tab11);
            app.Image11.ImageSource = fullfile(pathToMLAPP, 'BD_01.png');
            app.Image2_12 = uiimage(app.Tab11);
            app.Image2_12.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab12 = uitab(app.TabGroup);
            app.Tab12.Title = 'Tab12';
            app.VolverButton_11 = uibutton(app.Tab12, 'push');
            app.VolverButton_11.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_11Pushed, true);
            app.VolverButton_11.Text = 'Volver';
            app.EnergiatermicaLabel = uilabel(app.Tab12);
            app.EnergiatermicaLabel.Text = 'Energia termica';
            app.Label_11 = uilabel(app.Tab12);
            app.Label_11.Text = {'El biogas como resultado del proceso en el biodigestor puede usarse como combustibe, en hornos, '; 'estufas, secadores, calderas u otros sistemas de combustión a gas, debidamente adaptados para tal efecto'};
            app.Image12 = uiimage(app.Tab12);
            app.Image12.ImageSource = fullfile(pathToMLAPP, 'gas-LP-870x600.jpg');
            app.Image2_13 = uiimage(app.Tab12);
            app.Image2_13.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Tab13 = uitab(app.TabGroup);
            app.Tab13.Title = 'Tab13';
            app.Acerpseudoplatanus = uiimage(app.Tab13);
            app.Acerpseudoplatanus.ImageSource = fullfile(pathToMLAPP, 'acer_pseudoplatanus.jpg');
            app.AcerpseudoplatanusLabel = uilabel(app.Tab13);
            app.AcerpseudoplatanusLabel.Text = 'Acer pseudoplatanus';
            app.AcerpseudoplatanusButton = uibutton(app.Tab13, 'push');
            app.AcerpseudoplatanusButton.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton.Text = 'Acer pseudoplatanus';
            app.AbedulButton = uibutton(app.Tab13, 'push');
            app.AbedulButton.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton.Text = 'Abedul';
            app.HayaButton = uibutton(app.Tab13, 'push');
            app.HayaButton.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton.Text = 'Haya';
            app.RobleButton = uibutton(app.Tab13, 'push');
            app.RobleButton.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton.Text = 'Roble';
            app.QuercuscerrisButton = uibutton(app.Tab13, 'push');
            app.QuercuscerrisButton.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton.Text = 'Quercus cerris';
            app.AlnusglutinosaButton = uibutton(app.Tab13, 'push');
            app.AlnusglutinosaButton.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton.Text = 'Alnus glutinosa';
            app.AlnusincanaButton = uibutton(app.Tab13, 'push');
            app.AlnusincanaButton.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton.Text = 'Alnus incana';
            app.FresnoButton = uibutton(app.Tab13, 'push');
            app.FresnoButton.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton.Text = 'Fresno';
            app.CarpeButton = uibutton(app.Tab13, 'push');
            app.CarpeButton.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton.Text = 'Carpe';
            app.OlmoButton = uibutton(app.Tab13, 'push');
            app.OlmoButton.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton.Text = 'Olmo';
            app.SalixalbaButton = uibutton(app.Tab13, 'push');
            app.SalixalbaButton.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton.Text = 'Salix alba';
            app.AbetorojoButton = uibutton(app.Tab13, 'push');
            app.AbetorojoButton.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton.Text = 'Abeto rojo';
            app.PinosilvestreButton = uibutton(app.Tab13, 'push');
            app.PinosilvestreButton.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton.Text = 'Pino silvestre';
            app.PinusstrobusButton = uibutton(app.Tab13, 'push');
            app.PinusstrobusButton.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton.Text = 'Pinus strobus';
            app.PinabeteButton = uibutton(app.Tab13, 'push');
            app.PinabeteButton.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton.Text = 'Pinabete';
            app.CarbonvegetalButton = uibutton(app.Tab13, 'push');
            app.CarbonvegetalButton.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton = uibutton(app.Tab13, 'push');
            app.InformacionbiodigestotresButton.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_14 = uiimage(app.Tab13);
            app.Image2_14.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.WillowLabel = uilabel(app.Tab13);
            app.WillowLabel.Text = '© Willow';
            app.SalirButton_2 = uibutton(app.Tab13, 'push');
            app.SalirButton_2.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_2.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_2.Text = 'Salir';
            app.Tab14 = uitab(app.TabGroup);
            app.Tab14.Title = 'Tab14';
            app.AbedulLabel = uilabel(app.Tab14);
            app.AbedulLabel.Text = 'Abedul';
            app.Abedul = uiimage(app.Tab14);
            app.Abedul.ImageSource = fullfile(pathToMLAPP, 'abedul.jpg');
            app.AcerpseudoplatanusButton_2 = uibutton(app.Tab14, 'push');
            app.AcerpseudoplatanusButton_2.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_2.Text = 'Acer pseudoplatanus';
            app.AbedulButton_2 = uibutton(app.Tab14, 'push');
            app.AbedulButton_2.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_2.Text = 'Abedul';
            app.HayaButton_2 = uibutton(app.Tab14, 'push');
            app.HayaButton_2.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_2.Text = 'Haya';
            app.RobleButton_2 = uibutton(app.Tab14, 'push');
            app.RobleButton_2.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_2.Text = 'Roble';
            app.QuercuscerrisButton_2 = uibutton(app.Tab14, 'push');
            app.QuercuscerrisButton_2.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_2.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_2 = uibutton(app.Tab14, 'push');
            app.AlnusglutinosaButton_2.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_2.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_2 = uibutton(app.Tab14, 'push');
            app.AlnusincanaButton_2.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_2.Text = 'Alnus incana';
            app.FresnoButton_2 = uibutton(app.Tab14, 'push');
            app.FresnoButton_2.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_2.Text = 'Fresno';
            app.CarpeButton_2 = uibutton(app.Tab14, 'push');
            app.CarpeButton_2.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_2.Text = 'Carpe';
            app.OlmoButton_2 = uibutton(app.Tab14, 'push');
            app.OlmoButton_2.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_2.Text = 'Olmo';
            app.SalixalbaButton_2 = uibutton(app.Tab14, 'push');
            app.SalixalbaButton_2.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_2.Text = 'Salix alba';
            app.AbetorojoButton_2 = uibutton(app.Tab14, 'push');
            app.AbetorojoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_2.Text = 'Abeto rojo';
            app.PinosilvestreButton_2 = uibutton(app.Tab14, 'push');
            app.PinosilvestreButton_2.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_2.Text = 'Pino silvestre';
            app.PinusstrobusButton_2 = uibutton(app.Tab14, 'push');
            app.PinusstrobusButton_2.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_2.Text = 'Pinus strobus';
            app.PinabeteButton_2 = uibutton(app.Tab14, 'push');
            app.PinabeteButton_2.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_2.Text = 'Pinabete';
            app.CarbonvegetalButton_2 = uibutton(app.Tab14, 'push');
            app.CarbonvegetalButton_2.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_2.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_2 = uibutton(app.Tab14, 'push');
            app.InformacionbiodigestotresButton_2.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_2.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_15 = uiimage(app.Tab14);
            app.Image2_15.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.PixabayLabel = uilabel(app.Tab14);
            app.PixabayLabel.Text = 'Pixabay';
            app.SalirButton_3 = uibutton(app.Tab14, 'push');
            app.SalirButton_3.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_3.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_3.Text = 'Salir';
            app.Tab15 = uitab(app.TabGroup);
            app.Tab15.Title = 'Tab15';
            app.HayaLabel = uilabel(app.Tab15);
            app.HayaLabel.Text = 'Haya';
            app.Haya = uiimage(app.Tab15);
            app.Haya.ImageSource = fullfile(pathToMLAPP, 'haya.jpeg');
            app.AcerpseudoplatanusButton_3 = uibutton(app.Tab15, 'push');
            app.AcerpseudoplatanusButton_3.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_3.Text = 'Acer pseudoplatanus';
            app.AbedulButton_3 = uibutton(app.Tab15, 'push');
            app.AbedulButton_3.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_3.Text = 'Abedul';
            app.HayaButton_3 = uibutton(app.Tab15, 'push');
            app.HayaButton_3.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_3.Text = 'Haya';
            app.RobleButton_3 = uibutton(app.Tab15, 'push');
            app.RobleButton_3.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_3.Text = 'Roble';
            app.QuercuscerrisButton_3 = uibutton(app.Tab15, 'push');
            app.QuercuscerrisButton_3.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_3.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_3 = uibutton(app.Tab15, 'push');
            app.AlnusglutinosaButton_3.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_3.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_3 = uibutton(app.Tab15, 'push');
            app.AlnusincanaButton_3.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_3.Text = 'Alnus incana';
            app.FresnoButton_3 = uibutton(app.Tab15, 'push');
            app.FresnoButton_3.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_3.Text = 'Fresno';
            app.CarpeButton_3 = uibutton(app.Tab15, 'push');
            app.CarpeButton_3.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_3.Text = 'Carpe';
            app.OlmoButton_3 = uibutton(app.Tab15, 'push');
            app.OlmoButton_3.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_3.Text = 'Olmo';
            app.SalixalbaButton_3 = uibutton(app.Tab15, 'push');
            app.SalixalbaButton_3.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_3.Text = 'Salix alba';
            app.AbetorojoButton_3 = uibutton(app.Tab15, 'push');
            app.AbetorojoButton_3.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_3.Text = 'Abeto rojo';
            app.PinosilvestreButton_3 = uibutton(app.Tab15, 'push');
            app.PinosilvestreButton_3.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_3.Text = 'Pino silvestre';
            app.PinusstrobusButton_3 = uibutton(app.Tab15, 'push');
            app.PinusstrobusButton_3.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_3.Text = 'Pinus strobus';
            app.PinabeteButton_3 = uibutton(app.Tab15, 'push');
            app.PinabeteButton_3.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_3.Text = 'Pinabete';
            app.CarbonvegetalButton_3 = uibutton(app.Tab15, 'push');
            app.CarbonvegetalButton_3.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_3.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_3 = uibutton(app.Tab15, 'push');
            app.InformacionbiodigestotresButton_3.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_3.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_16 = uiimage(app.Tab15);
            app.Image2_16.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.RosaMariaRinklLabel = uilabel(app.Tab15);
            app.RosaMariaRinklLabel.Text = '© Rosa-Maria Rinkl';
            app.SalirButton_4 = uibutton(app.Tab15, 'push');
            app.SalirButton_4.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_4.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_4.Text = 'Salir';
            app.Tab16 = uitab(app.TabGroup);
            app.Tab16.Title = 'Tab16';
            app.RobleLabel = uilabel(app.Tab16);
            app.RobleLabel.Text = 'Roble';
            app.Roble = uiimage(app.Tab16);
            app.Roble.ImageSource = fullfile(pathToMLAPP, 'roble.jpeg');
            app.AcerpseudoplatanusButton_4 = uibutton(app.Tab16, 'push');
            app.AcerpseudoplatanusButton_4.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_4.Text = 'Acer pseudoplatanus';
            app.AbedulButton_4 = uibutton(app.Tab16, 'push');
            app.AbedulButton_4.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_4.Text = 'Abedul';
            app.HayaButton_4 = uibutton(app.Tab16, 'push');
            app.HayaButton_4.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_4.Text = 'Haya';
            app.RobleButton_4 = uibutton(app.Tab16, 'push');
            app.RobleButton_4.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_4.Text = 'Roble';
            app.QuercuscerrisButton_4 = uibutton(app.Tab16, 'push');
            app.QuercuscerrisButton_4.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_4.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_4 = uibutton(app.Tab16, 'push');
            app.AlnusglutinosaButton_4.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_4.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_4 = uibutton(app.Tab16, 'push');
            app.AlnusincanaButton_4.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_4.Text = 'Alnus incana';
            app.FresnoButton_4 = uibutton(app.Tab16, 'push');
            app.FresnoButton_4.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_4.Text = 'Fresno';
            app.CarpeButton_4 = uibutton(app.Tab16, 'push');
            app.CarpeButton_4.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_4.Text = 'Carpe';
            app.OlmoButton_4 = uibutton(app.Tab16, 'push');
            app.OlmoButton_4.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_4.Text = 'Olmo';
            app.SalixalbaButton_4 = uibutton(app.Tab16, 'push');
            app.SalixalbaButton_4.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_4.Text = 'Salix alba';
            app.AbetorojoButton_4 = uibutton(app.Tab16, 'push');
            app.AbetorojoButton_4.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_4.Text = 'Abeto rojo';
            app.PinosilvestreButton_4 = uibutton(app.Tab16, 'push');
            app.PinosilvestreButton_4.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_4.Text = 'Pino silvestre';
            app.PinusstrobusButton_4 = uibutton(app.Tab16, 'push');
            app.PinusstrobusButton_4.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_4.Text = 'Pinus strobus';
            app.PinabeteButton_4 = uibutton(app.Tab16, 'push');
            app.PinabeteButton_4.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_4.Text = 'Pinabete';
            app.CarbonvegetalButton_4 = uibutton(app.Tab16, 'push');
            app.CarbonvegetalButton_4.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_4.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_4 = uibutton(app.Tab16, 'push');
            app.InformacionbiodigestotresButton_4.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_4.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_17 = uiimage(app.Tab16);
            app.Image2_17.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.MaEugeniaMendiolaGonzlezLabel = uilabel(app.Tab16);
            app.MaEugeniaMendiolaGonzlezLabel.Text = '© Ma. Eugenia Mendiola González';
            app.SalirButton_5 = uibutton(app.Tab16, 'push');
            app.SalirButton_5.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_5.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_5.Text = 'Salir';
            app.Tab17 = uitab(app.TabGroup);
            app.Tab17.Title = 'Tab17';
            app.QuercuscerrisLabel = uilabel(app.Tab17);
            app.QuercuscerrisLabel.Text = 'Quercus cerris';
            app.Roble_2 = uiimage(app.Tab17);
            app.Roble_2.ImageSource = fullfile(pathToMLAPP, 'quercus cerris.jpeg');
            app.AcerpseudoplatanusButton_5 = uibutton(app.Tab17, 'push');
            app.AcerpseudoplatanusButton_5.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_5.Text = 'Acer pseudoplatanus';
            app.AbedulButton_5 = uibutton(app.Tab17, 'push');
            app.AbedulButton_5.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_5.Text = 'Abedul';
            app.HayaButton_5 = uibutton(app.Tab17, 'push');
            app.HayaButton_5.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_5.Text = 'Haya';
            app.RobleButton_5 = uibutton(app.Tab17, 'push');
            app.RobleButton_5.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_5.Text = 'Roble';
            app.QuercuscerrisButton_5 = uibutton(app.Tab17, 'push');
            app.QuercuscerrisButton_5.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_5.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_5 = uibutton(app.Tab17, 'push');
            app.AlnusglutinosaButton_5.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_5.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_5 = uibutton(app.Tab17, 'push');
            app.AlnusincanaButton_5.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_5.Text = 'Alnus incana';
            app.FresnoButton_5 = uibutton(app.Tab17, 'push');
            app.FresnoButton_5.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_5.Text = 'Fresno';
            app.CarpeButton_5 = uibutton(app.Tab17, 'push');
            app.CarpeButton_5.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_5.Text = 'Carpe';
            app.OlmoButton_5 = uibutton(app.Tab17, 'push');
            app.OlmoButton_5.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_5.Text = 'Olmo';
            app.SalixalbaButton_5 = uibutton(app.Tab17, 'push');
            app.SalixalbaButton_5.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_5.Text = 'Salix alba';
            app.AbetorojoButton_5 = uibutton(app.Tab17, 'push');
            app.AbetorojoButton_5.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_5.Text = 'Abeto rojo';
            app.PinosilvestreButton_5 = uibutton(app.Tab17, 'push');
            app.PinosilvestreButton_5.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_5.Text = 'Pino silvestre';
            app.PinusstrobusButton_5 = uibutton(app.Tab17, 'push');
            app.PinusstrobusButton_5.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_5.Text = 'Pinus strobus';
            app.PinabeteButton_5 = uibutton(app.Tab17, 'push');
            app.PinabeteButton_5.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_5.Text = 'Pinabete';
            app.CarbonvegetalButton_5 = uibutton(app.Tab17, 'push');
            app.CarbonvegetalButton_5.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_5.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_5 = uibutton(app.Tab17, 'push');
            app.InformacionbiodigestotresButton_5.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_5.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_18 = uiimage(app.Tab17);
            app.Image2_18.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.StephenJamesMcWilliamLabel = uilabel(app.Tab17);
            app.StephenJamesMcWilliamLabel.Text = 'Stephen James McWilliam';
            app.SalirButton_6 = uibutton(app.Tab17, 'push');
            app.SalirButton_6.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_6.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_6.Text = 'Salir';
            app.Tab18 = uitab(app.TabGroup);
            app.Tab18.Title = 'Tab18';
            app.AlnusglutinosaLabel = uilabel(app.Tab18);
            app.AlnusglutinosaLabel.Text = 'Alnus glutinosa';
            app.Roble_3 = uiimage(app.Tab18);
            app.Roble_3.ImageSource = fullfile(pathToMLAPP, 'alnus glutinosa.jpg');
            app.AcerpseudoplatanusButton_6 = uibutton(app.Tab18, 'push');
            app.AcerpseudoplatanusButton_6.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_6.Text = 'Acer pseudoplatanus';
            app.AbedulButton_6 = uibutton(app.Tab18, 'push');
            app.AbedulButton_6.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_6.Text = 'Abedul';
            app.HayaButton_6 = uibutton(app.Tab18, 'push');
            app.HayaButton_6.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_6.Text = 'Haya';
            app.RobleButton_6 = uibutton(app.Tab18, 'push');
            app.RobleButton_6.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_6.Text = 'Roble';
            app.QuercuscerrisButton_6 = uibutton(app.Tab18, 'push');
            app.QuercuscerrisButton_6.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_6.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_6 = uibutton(app.Tab18, 'push');
            app.AlnusglutinosaButton_6.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_6.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_6 = uibutton(app.Tab18, 'push');
            app.AlnusincanaButton_6.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_6.Text = 'Alnus incana';
            app.FresnoButton_6 = uibutton(app.Tab18, 'push');
            app.FresnoButton_6.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_6.Text = 'Fresno';
            app.CarpeButton_6 = uibutton(app.Tab18, 'push');
            app.CarpeButton_6.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_6.Text = 'Carpe';
            app.OlmoButton_6 = uibutton(app.Tab18, 'push');
            app.OlmoButton_6.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_6.Text = 'Olmo';
            app.SalixalbaButton_6 = uibutton(app.Tab18, 'push');
            app.SalixalbaButton_6.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_6.Text = 'Salix alba';
            app.AbetorojoButton_6 = uibutton(app.Tab18, 'push');
            app.AbetorojoButton_6.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_6.Text = 'Abeto rojo';
            app.PinosilvestreButton_6 = uibutton(app.Tab18, 'push');
            app.PinosilvestreButton_6.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_6.Text = 'Pino silvestre';
            app.PinusstrobusButton_6 = uibutton(app.Tab18, 'push');
            app.PinusstrobusButton_6.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_6.Text = 'Pinus strobus';
            app.PinabeteButton_6 = uibutton(app.Tab18, 'push');
            app.PinabeteButton_6.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_6.Text = 'Pinabete';
            app.CarbonvegetalButton_6 = uibutton(app.Tab18, 'push');
            app.CarbonvegetalButton_6.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_6.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_6 = uibutton(app.Tab18, 'push');
            app.InformacionbiodigestotresButton_6.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_6.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_19 = uiimage(app.Tab18);
            app.Image2_19.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.AlexeyPSereginLabel = uilabel(app.Tab18);
            app.AlexeyPSereginLabel.Text = '© Alexey P. Seregin';
            app.SalirButton_7 = uibutton(app.Tab18, 'push');
            app.SalirButton_7.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_7.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_7.Text = 'Salir';
            app.Tab19 = uitab(app.TabGroup);
            app.Tab19.Title = 'Tab19';
            app.AlnusincanaLabel = uilabel(app.Tab19);
            app.AlnusincanaLabel.Text = 'Alnus incana';
            app.Roble_4 = uiimage(app.Tab19);
            app.Roble_4.ImageSource = fullfile(pathToMLAPP, 'alnus incana.jpg');
            app.AcerpseudoplatanusButton_7 = uibutton(app.Tab19, 'push');
            app.AcerpseudoplatanusButton_7.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_7.Text = 'Acer pseudoplatanus';
            app.AbedulButton_7 = uibutton(app.Tab19, 'push');
            app.AbedulButton_7.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_7.Text = 'Abedul';
            app.HayaButton_7 = uibutton(app.Tab19, 'push');
            app.HayaButton_7.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_7.Text = 'Haya';
            app.RobleButton_7 = uibutton(app.Tab19, 'push');
            app.RobleButton_7.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_7.Text = 'Roble';
            app.QuercuscerrisButton_7 = uibutton(app.Tab19, 'push');
            app.QuercuscerrisButton_7.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_7.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_7 = uibutton(app.Tab19, 'push');
            app.AlnusglutinosaButton_7.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_7.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_7 = uibutton(app.Tab19, 'push');
            app.AlnusincanaButton_7.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_7.Text = 'Alnus incana';
            app.FresnoButton_7 = uibutton(app.Tab19, 'push');
            app.FresnoButton_7.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_7.Text = 'Fresno';
            app.CarpeButton_7 = uibutton(app.Tab19, 'push');
            app.CarpeButton_7.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_7.Text = 'Carpe';
            app.OlmoButton_7 = uibutton(app.Tab19, 'push');
            app.OlmoButton_7.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_7.Text = 'Olmo';
            app.SalixalbaButton_7 = uibutton(app.Tab19, 'push');
            app.SalixalbaButton_7.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_7.Text = 'Salix alba';
            app.AbetorojoButton_7 = uibutton(app.Tab19, 'push');
            app.AbetorojoButton_7.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_7.Text = 'Abeto rojo';
            app.PinosilvestreButton_7 = uibutton(app.Tab19, 'push');
            app.PinosilvestreButton_7.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_7.Text = 'Pino silvestre';
            app.PinusstrobusButton_7 = uibutton(app.Tab19, 'push');
            app.PinusstrobusButton_7.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_7.Text = 'Pinus strobus';
            app.PinabeteButton_7 = uibutton(app.Tab19, 'push');
            app.PinabeteButton_7.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_7.Text = 'Pinabete';
            app.CarbonvegetalButton_7 = uibutton(app.Tab19, 'push');
            app.CarbonvegetalButton_7.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_7.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_7 = uibutton(app.Tab19, 'push');
            app.InformacionbiodigestotresButton_7.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_7.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_20 = uiimage(app.Tab19);
            app.Image2_20.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.SteveFahmieLabel = uilabel(app.Tab19);
            app.SteveFahmieLabel.Text = '© Steve Fahmie';
            app.SalirButton_8 = uibutton(app.Tab19, 'push');

            app.SalirButton_8.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_8.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_8.Text = 'Salir';
            app.Tab20 = uitab(app.TabGroup);
            app.Tab20.Title = 'Tab20';
            app.FresnoLabel = uilabel(app.Tab20);
            app.FresnoLabel.Text = 'Fresno';
            app.Roble_5 = uiimage(app.Tab20);
            app.Roble_5.ImageSource = fullfile(pathToMLAPP, 'fresno.jpg');
            app.AcerpseudoplatanusButton_8 = uibutton(app.Tab20, 'push');
            app.AcerpseudoplatanusButton_8.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_8.Text = 'Acer pseudoplatanus';
            app.AbedulButton_8 = uibutton(app.Tab20, 'push');
            app.AbedulButton_8.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_8.Text = 'Abedul';
            app.HayaButton_8 = uibutton(app.Tab20, 'push');
            app.HayaButton_8.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_8.Text = 'Haya';
            app.RobleButton_8 = uibutton(app.Tab20, 'push');
            app.RobleButton_8.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_8.Text = 'Roble';
            app.QuercuscerrisButton_8 = uibutton(app.Tab20, 'push');
            app.QuercuscerrisButton_8.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_8.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_8 = uibutton(app.Tab20, 'push');
            app.AlnusglutinosaButton_8.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_8.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_8 = uibutton(app.Tab20, 'push');
            app.AlnusincanaButton_8.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_8.Text = 'Alnus incana';
            app.FresnoButton_8 = uibutton(app.Tab20, 'push');
            app.FresnoButton_8.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_8.Text = 'Fresno';
            app.CarpeButton_8 = uibutton(app.Tab20, 'push');
            app.CarpeButton_8.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_8.Text = 'Carpe';
            app.OlmoButton_8 = uibutton(app.Tab20, 'push');
            app.OlmoButton_8.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_8.Text = 'Olmo';
            app.SalixalbaButton_8 = uibutton(app.Tab20, 'push');
            app.SalixalbaButton_8.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_8.Text = 'Salix alba';
            app.AbetorojoButton_8 = uibutton(app.Tab20, 'push');
            app.AbetorojoButton_8.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_8.Text = 'Abeto rojo';
            app.PinosilvestreButton_8 = uibutton(app.Tab20, 'push');
            app.PinosilvestreButton_8.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_8.Text = 'Pino silvestre';
            app.PinusstrobusButton_8 = uibutton(app.Tab20, 'push');
            app.PinusstrobusButton_8.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_8.Text = 'Pinus strobus';
            app.PinabeteButton_8 = uibutton(app.Tab20, 'push');
            app.PinabeteButton_8.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_8.Text = 'Pinabete';
            app.CarbonvegetalButton_8 = uibutton(app.Tab20, 'push');
            app.CarbonvegetalButton_8.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_8.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_8 = uibutton(app.Tab20, 'push');
            app.InformacionbiodigestotresButton_8.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_8.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_21 = uiimage(app.Tab20);
            app.Image2_21.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.AntonioSarabiaLabel = uilabel(app.Tab20);
            app.AntonioSarabiaLabel.Text = '© Antonio Sarabia';
            app.SalirButton_9 = uibutton(app.Tab20, 'push');
            app.SalirButton_9.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_9.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_9.Text = 'Salir';
            app.Tab21 = uitab(app.TabGroup);
            app.Tab21.Title = 'Tab21';
            app.CarpeLabel = uilabel(app.Tab21);
            app.CarpeLabel.Text = 'Carpe';
            app.Roble_6 = uiimage(app.Tab21);
            app.Roble_6.ImageSource = fullfile(pathToMLAPP, 'carpe.jpg');
            app.AcerpseudoplatanusButton_9 = uibutton(app.Tab21, 'push');
            app.AcerpseudoplatanusButton_9.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_9.Text = 'Acer pseudoplatanus';
            app.AbedulButton_9 = uibutton(app.Tab21, 'push');
            app.AbedulButton_9.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_9.Text = 'Abedul';
            app.HayaButton_9 = uibutton(app.Tab21, 'push');
            app.HayaButton_9.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_9.Text = 'Haya';
            app.RobleButton_9 = uibutton(app.Tab21, 'push');
            app.RobleButton_9.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_9.Text = 'Roble';
            app.QuercuscerrisButton_9 = uibutton(app.Tab21, 'push');
            app.QuercuscerrisButton_9.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_9.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_9 = uibutton(app.Tab21, 'push');
            app.AlnusglutinosaButton_9.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_9.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_9 = uibutton(app.Tab21, 'push');
            app.AlnusincanaButton_9.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_9.Text = 'Alnus incana';
            app.FresnoButton_9 = uibutton(app.Tab21, 'push');
            app.FresnoButton_9.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_9.Text = 'Fresno';
            app.CarpeButton_9 = uibutton(app.Tab21, 'push');
            app.CarpeButton_9.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_9.Text = 'Carpe';
            app.OlmoButton_9 = uibutton(app.Tab21, 'push');
            app.OlmoButton_9.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_9.Text = 'Olmo';
            app.SalixalbaButton_9 = uibutton(app.Tab21, 'push');
            app.SalixalbaButton_9.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_9.Text = 'Salix alba';
            app.AbetorojoButton_9 = uibutton(app.Tab21, 'push');
            app.AbetorojoButton_9.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_9.Text = 'Abeto rojo';
            app.PinosilvestreButton_9 = uibutton(app.Tab21, 'push');
            app.PinosilvestreButton_9.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_9.Text = 'Pino silvestre';
            app.PinusstrobusButton_9 = uibutton(app.Tab21, 'push');
            app.PinusstrobusButton_9.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_9.Text = 'Pinus strobus';
            app.PinabeteButton_9 = uibutton(app.Tab21, 'push');
            app.PinabeteButton_9.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_9.Text = 'Pinabete';
            app.CarbonvegetalButton_9 = uibutton(app.Tab21, 'push');
            app.CarbonvegetalButton_9.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_9.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_9 = uibutton(app.Tab21, 'push');
            app.InformacionbiodigestotresButton_9.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_9.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_22 = uiimage(app.Tab21);
            app.Image2_22.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.CarolHowardDonatiLabel = uilabel(app.Tab21);
            app.CarolHowardDonatiLabel.Text = '© Carol Howard Donati';
            app.SalirButton_10 = uibutton(app.Tab21, 'push');
            app.SalirButton_10.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_10.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_10.Text = 'Salir';
            app.Tab22 = uitab(app.TabGroup);
            app.Tab22.Title = 'Tab22';
            app.OlmoLabel = uilabel(app.Tab22);
            app.OlmoLabel.Text = 'Olmo';
            app.Roble_7 = uiimage(app.Tab22);
            app.Roble_7.ImageSource = fullfile(pathToMLAPP, 'olmo.jpg');
            app.AcerpseudoplatanusButton_10 = uibutton(app.Tab22, 'push');
            app.AcerpseudoplatanusButton_10.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_10.Text = 'Acer pseudoplatanus';
            app.AbedulButton_10 = uibutton(app.Tab22, 'push');
            app.AbedulButton_10.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_10.Text = 'Abedul';
            app.HayaButton_10 = uibutton(app.Tab22, 'push');
            app.HayaButton_10.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_10.Text = 'Haya';
            app.RobleButton_10 = uibutton(app.Tab22, 'push');
            app.RobleButton_10.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_10.Text = 'Roble';
            app.QuercuscerrisButton_10 = uibutton(app.Tab22, 'push');
            app.QuercuscerrisButton_10.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_10.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_10 = uibutton(app.Tab22, 'push');
            app.AlnusglutinosaButton_10.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_10.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_10 = uibutton(app.Tab22, 'push');
            app.AlnusincanaButton_10.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_10.Text = 'Alnus incana';
            app.FresnoButton_10 = uibutton(app.Tab22, 'push');
            app.FresnoButton_10.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_10.Text = 'Fresno';
            app.CarpeButton_10 = uibutton(app.Tab22, 'push');
            app.CarpeButton_10.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_10.Text = 'Carpe';
            app.OlmoButton_10 = uibutton(app.Tab22, 'push');
            app.OlmoButton_10.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_10.Text = 'Olmo';
            app.SalixalbaButton_10 = uibutton(app.Tab22, 'push');
            app.SalixalbaButton_10.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_10.Text = 'Salix alba';
            app.AbetorojoButton_10 = uibutton(app.Tab22, 'push');
            app.AbetorojoButton_10.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_10.Text = 'Abeto rojo';
            app.PinosilvestreButton_10 = uibutton(app.Tab22, 'push');
            app.PinosilvestreButton_10.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_10.Text = 'Pino silvestre';
            app.PinusstrobusButton_10 = uibutton(app.Tab22, 'push');
            app.PinusstrobusButton_10.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_10.Text = 'Pinus strobus';
            app.PinabeteButton_10 = uibutton(app.Tab22, 'push');
            app.PinabeteButton_10.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_10.Text = 'Pinabete';
            app.CarbonvegetalButton_10 = uibutton(app.Tab22, 'push');
            app.CarbonvegetalButton_10.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_10.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_10 = uibutton(app.Tab22, 'push');
            app.InformacionbiodigestotresButton_10.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_10.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_23 = uiimage(app.Tab22);
            app.Image2_23.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.DouglasGoldmanLabel = uilabel(app.Tab22);
            app.DouglasGoldmanLabel.Text = '© Douglas Goldman';
            app.SalirButton_11 = uibutton(app.Tab22, 'push');
            app.SalirButton_11.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_11.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_11.Text = 'Salir';
            app.Tab23 = uitab(app.TabGroup);
            app.Tab23.Title = 'Tab23';
            app.SalixalbaLabel = uilabel(app.Tab23);
            app.SalixalbaLabel.Text = 'Salix alba';
            app.Roble_8 = uiimage(app.Tab23);
            app.Roble_8.ImageSource = fullfile(pathToMLAPP, 'salix alba.jpeg');
            app.AcerpseudoplatanusButton_11 = uibutton(app.Tab23, 'push');
            app.AcerpseudoplatanusButton_11.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_11.Text = 'Acer pseudoplatanus';
            app.AbedulButton_11 = uibutton(app.Tab23, 'push');
            app.AbedulButton_11.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_11.Text = 'Abedul';
            app.HayaButton_11 = uibutton(app.Tab23, 'push');
            app.HayaButton_11.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_11.Text = 'Haya';
            app.RobleButton_11 = uibutton(app.Tab23, 'push');
            app.RobleButton_11.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_11.Text = 'Roble';
            app.QuercuscerrisButton_11 = uibutton(app.Tab23, 'push');
            app.QuercuscerrisButton_11.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_11.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_11 = uibutton(app.Tab23, 'push');
            app.AlnusglutinosaButton_11.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_11.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_11 = uibutton(app.Tab23, 'push');
            app.AlnusincanaButton_11.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_11.Text = 'Alnus incana';
            app.FresnoButton_11 = uibutton(app.Tab23, 'push');
            app.FresnoButton_11.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_11.Text = 'Fresno';
            app.CarpeButton_11 = uibutton(app.Tab23, 'push');
            app.CarpeButton_11.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_11.Text = 'Carpe';
            app.OlmoButton_11 = uibutton(app.Tab23, 'push');
            app.OlmoButton_11.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_11.Text = 'Olmo';
            app.SalixalbaButton_11 = uibutton(app.Tab23, 'push');
            app.SalixalbaButton_11.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_11.Text = 'Salix alba';
            app.AbetorojoButton_11 = uibutton(app.Tab23, 'push');
            app.AbetorojoButton_11.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_11.Text = 'Abeto rojo';
            app.PinosilvestreButton_11 = uibutton(app.Tab23, 'push');
            app.PinosilvestreButton_11.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_11.Text = 'Pino silvestre';
            app.PinusstrobusButton_11 = uibutton(app.Tab23, 'push');
            app.PinusstrobusButton_11.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_11.Text = 'Pinus strobus';
            app.PinabeteButton_11 = uibutton(app.Tab23, 'push');
            app.PinabeteButton_11.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_11.Text = 'Pinabete';
            app.CarbonvegetalButton_11 = uibutton(app.Tab23, 'push');
            app.CarbonvegetalButton_11.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_11.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_11 = uibutton(app.Tab23, 'push');
            app.InformacionbiodigestotresButton_11.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_11.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_24 = uiimage(app.Tab23);
            app.Image2_24.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.MathildePayrastreLabel = uilabel(app.Tab23);
            app.MathildePayrastreLabel.Text = '© Mathilde Payrastre';
            app.SalirButton_12 = uibutton(app.Tab23, 'push');
            app.SalirButton_12.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_12.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_12.Text = 'Salir';
            app.Tab24 = uitab(app.TabGroup);
            app.Tab24.Title = 'Tab24';
            app.AbetorojoLabel = uilabel(app.Tab24);
            app.AbetorojoLabel.Text = 'Abeto rojo';
            app.Roble_9 = uiimage(app.Tab24);
            app.Roble_9.ImageSource = fullfile(pathToMLAPP, 'abeto_rojo.jpeg');
            app.AcerpseudoplatanusButton_12 = uibutton(app.Tab24, 'push');
            app.AcerpseudoplatanusButton_12.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_12.Text = 'Acer pseudoplatanus';
            app.AbedulButton_12 = uibutton(app.Tab24, 'push');
            app.AbedulButton_12.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_12.Text = 'Abedul';
            app.HayaButton_12 = uibutton(app.Tab24, 'push');
            app.HayaButton_12.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_12.Text = 'Haya';
            app.RobleButton_12 = uibutton(app.Tab24, 'push');
            app.RobleButton_12.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_12.Text = 'Roble';
            app.QuercuscerrisButton_12 = uibutton(app.Tab24, 'push');
            app.QuercuscerrisButton_12.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_12.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_12 = uibutton(app.Tab24, 'push');
            app.AlnusglutinosaButton_12.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_12.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_12 = uibutton(app.Tab24, 'push');
            app.AlnusincanaButton_12.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_12.Text = 'Alnus incana';
            app.FresnoButton_12 = uibutton(app.Tab24, 'push');
            app.FresnoButton_12.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_12.Text = 'Fresno';
            app.CarpeButton_12 = uibutton(app.Tab24, 'push');
            app.CarpeButton_12.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_12.Text = 'Carpe';
            app.OlmoButton_12 = uibutton(app.Tab24, 'push');
            app.OlmoButton_12.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_12.Text = 'Olmo';
            app.SalixalbaButton_12 = uibutton(app.Tab24, 'push');
            app.SalixalbaButton_12.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_12.Text = 'Salix alba';
            app.AbetorojoButton_12 = uibutton(app.Tab24, 'push');
            app.AbetorojoButton_12.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_12.Text = 'Abeto rojo';
            app.PinosilvestreButton_12 = uibutton(app.Tab24, 'push');
            app.PinosilvestreButton_12.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_12.Text = 'Pino silvestre';
            app.PinusstrobusButton_12 = uibutton(app.Tab24, 'push');
            app.PinusstrobusButton_12.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_12.Text = 'Pinus strobus';
            app.PinabeteButton_12 = uibutton(app.Tab24, 'push');
            app.PinabeteButton_12.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_12.Text = 'Pinabete';
            app.CarbonvegetalButton_12 = uibutton(app.Tab24, 'push');
            app.CarbonvegetalButton_12.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_12.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_12 = uibutton(app.Tab24, 'push');
            app.InformacionbiodigestotresButton_12.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_12.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_25 = uiimage(app.Tab24);
            app.Image2_25.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.kelwickLabel = uilabel(app.Tab24);
            app.kelwickLabel.Text = '© kelwick';
            app.SalirButton_13 = uibutton(app.Tab24, 'push');
            app.SalirButton_13.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_13.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_13.Text = 'Salir';
            app.Tab25 = uitab(app.TabGroup);
            app.Tab25.Title = 'Tab25';
            app.PinosilvestreLabel = uilabel(app.Tab25);
            app.PinosilvestreLabel.Text = 'Pino silvestre';
            app.Roble_10 = uiimage(app.Tab25);
            app.Roble_10.ImageSource = fullfile(pathToMLAPP, 'pino silvestre.jpeg');
            app.AcerpseudoplatanusButton_13 = uibutton(app.Tab25, 'push');
            app.AcerpseudoplatanusButton_13.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_13.Text = 'Acer pseudoplatanus';
            app.AbedulButton_13 = uibutton(app.Tab25, 'push');
            app.AbedulButton_13.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_13.Text = 'Abedul';
            app.HayaButton_13 = uibutton(app.Tab25, 'push');
            app.HayaButton_13.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_13.Text = 'Haya';
            app.RobleButton_13 = uibutton(app.Tab25, 'push');
            app.RobleButton_13.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_13.Text = 'Roble';
            app.QuercuscerrisButton_13 = uibutton(app.Tab25, 'push');
            app.QuercuscerrisButton_13.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_13.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_13 = uibutton(app.Tab25, 'push');
            app.AlnusglutinosaButton_13.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_13.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_13 = uibutton(app.Tab25, 'push');
            app.AlnusincanaButton_13.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_13.Text = 'Alnus incana';
            app.FresnoButton_13 = uibutton(app.Tab25, 'push');
            app.FresnoButton_13.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_13.Text = 'Fresno';
            app.CarpeButton_13 = uibutton(app.Tab25, 'push');
            app.CarpeButton_13.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_13.Text = 'Carpe';
            app.OlmoButton_13 = uibutton(app.Tab25, 'push');
            app.OlmoButton_13.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_13.Text = 'Olmo';
            app.SalixalbaButton_13 = uibutton(app.Tab25, 'push');
            app.SalixalbaButton_13.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_13.Text = 'Salix alba';
            app.AbetorojoButton_13 = uibutton(app.Tab25, 'push');
            app.AbetorojoButton_13.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_13.Text = 'Abeto rojo';
            app.PinosilvestreButton_13 = uibutton(app.Tab25, 'push');
            app.PinosilvestreButton_13.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_13.Text = 'Pino silvestre';
            app.PinusstrobusButton_13 = uibutton(app.Tab25, 'push');
            app.PinusstrobusButton_13.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_13.Text = 'Pinus strobus';
            app.PinabeteButton_13 = uibutton(app.Tab25, 'push');
            app.PinabeteButton_13.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_13.Text = 'Pinabete';
            app.CarbonvegetalButton_13 = uibutton(app.Tab25, 'push');
            app.CarbonvegetalButton_13.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_13.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_13 = uibutton(app.Tab25, 'push');
            app.InformacionbiodigestotresButton_13.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_13.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_26 = uiimage(app.Tab25);
            app.Image2_26.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.mustafagkmenLabel = uilabel(app.Tab25);
            app.mustafagkmenLabel.Text = '© mustafa gökmen';
            app.SalirButton_14 = uibutton(app.Tab25, 'push');
            app.SalirButton_14.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_14.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_14.Text = 'Salir';
            app.Tab26 = uitab(app.TabGroup);
            app.Tab26.Title = 'Tab26';
            app.PinusstrobusLabel = uilabel(app.Tab26);
            app.PinusstrobusLabel.Text = 'Pinus strobus';
            app.Roble_11 = uiimage(app.Tab26);
            app.Roble_11.ImageSource = fullfile(pathToMLAPP, 'pinus_strobus.jpg');
            app.AcerpseudoplatanusButton_14 = uibutton(app.Tab26, 'push');
            app.AcerpseudoplatanusButton_14.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_14.Text = 'Acer pseudoplatanus';
            app.AbedulButton_14 = uibutton(app.Tab26, 'push');
            app.AbedulButton_14.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_14.Text = 'Abedul';
            app.HayaButton_14 = uibutton(app.Tab26, 'push');
            app.HayaButton_14.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_14.Text = 'Haya';
            app.RobleButton_14 = uibutton(app.Tab26, 'push');
            app.RobleButton_14.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_14.Text = 'Roble';
            app.QuercuscerrisButton_14 = uibutton(app.Tab26, 'push');
            app.QuercuscerrisButton_14.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_14.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_14 = uibutton(app.Tab26, 'push');
            app.AlnusglutinosaButton_14.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_14.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_14 = uibutton(app.Tab26, 'push');
            app.AlnusincanaButton_14.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_14.Text = 'Alnus incana';
            app.FresnoButton_14 = uibutton(app.Tab26, 'push');
            app.FresnoButton_14.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_14.Text = 'Fresno';
            app.CarpeButton_14 = uibutton(app.Tab26, 'push');
            app.CarpeButton_14.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_14.Text = 'Carpe';
            app.OlmoButton_14 = uibutton(app.Tab26, 'push');
            app.OlmoButton_14.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_14.Text = 'Olmo';
            app.SalixalbaButton_14 = uibutton(app.Tab26, 'push');
            app.SalixalbaButton_14.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_14.Text = 'Salix alba';
            app.AbetorojoButton_14 = uibutton(app.Tab26, 'push');
            app.AbetorojoButton_14.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_14.Text = 'Abeto rojo';
            app.PinosilvestreButton_14 = uibutton(app.Tab26, 'push');
            app.PinosilvestreButton_14.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_14.Text = 'Pino silvestre';
            app.PinusstrobusButton_14 = uibutton(app.Tab26, 'push');
            app.PinusstrobusButton_14.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_14.Text = 'Pinus strobus';
            app.PinabeteButton_14 = uibutton(app.Tab26, 'push');
            app.PinabeteButton_14.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_14.Text = 'Pinabete';
            app.CarbonvegetalButton_14 = uibutton(app.Tab26, 'push');
            app.CarbonvegetalButton_14.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_14.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_14 = uibutton(app.Tab26, 'push');
            app.InformacionbiodigestotresButton_14.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_14.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_27 = uiimage(app.Tab26);
            app.Image2_27.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.coppodoLabel = uilabel(app.Tab26);
            app.coppodoLabel.Text = '© copépodo';
            app.SalirButton_15 = uibutton(app.Tab26, 'push');
            app.SalirButton_15.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_15.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_15.Text = 'Salir';
            app.Tab27 = uitab(app.TabGroup);
            app.Tab27.Title = 'Tab27';
            app.PinabeteLabel = uilabel(app.Tab27);
            app.PinabeteLabel.Text = 'Pinabete';
            app.Roble_12 = uiimage(app.Tab27);
            app.Roble_12.ImageSource = fullfile(pathToMLAPP, 'pinabete.jpeg');
            app.AcerpseudoplatanusButton_15 = uibutton(app.Tab27, 'push');
            app.AcerpseudoplatanusButton_15.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_15.Text = 'Acer pseudoplatanus';
            app.AbedulButton_15 = uibutton(app.Tab27, 'push');
            app.AbedulButton_15.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_15.Text = 'Abedul';
            app.HayaButton_15 = uibutton(app.Tab27, 'push');
            app.HayaButton_15.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_15.Text = 'Haya';
            app.RobleButton_15 = uibutton(app.Tab27, 'push');
            app.RobleButton_15.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_15.Text = 'Roble';
            app.QuercuscerrisButton_15 = uibutton(app.Tab27, 'push');
            app.QuercuscerrisButton_15.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_15.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_15 = uibutton(app.Tab27, 'push');
            app.AlnusglutinosaButton_15.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_15.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_15 = uibutton(app.Tab27, 'push');
            app.AlnusincanaButton_15.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_15.Text = 'Alnus incana';
            app.FresnoButton_15 = uibutton(app.Tab27, 'push');
            app.FresnoButton_15.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_15.Text = 'Fresno';
            app.CarpeButton_15 = uibutton(app.Tab27, 'push');
            app.CarpeButton_15.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_15.Text = 'Carpe';
            app.OlmoButton_15 = uibutton(app.Tab27, 'push');
            app.OlmoButton_15.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_15.Text = 'Olmo';
            app.SalixalbaButton_15 = uibutton(app.Tab27, 'push');
            app.SalixalbaButton_15.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_15.Text = 'Salix alba';
            app.AbetorojoButton_15 = uibutton(app.Tab27, 'push');
            app.AbetorojoButton_15.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_15.Text = 'Abeto rojo';
            app.PinosilvestreButton_15 = uibutton(app.Tab27, 'push');
            app.PinosilvestreButton_15.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_15.Text = 'Pino silvestre';
            app.PinusstrobusButton_15 = uibutton(app.Tab27, 'push');
            app.PinusstrobusButton_15.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_15.Text = 'Pinus strobus';
            app.PinabeteButton_15 = uibutton(app.Tab27, 'push');
            app.PinabeteButton_15.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_15.Text = 'Pinabete';
            app.CarbonvegetalButton_15 = uibutton(app.Tab27, 'push');
            app.CarbonvegetalButton_15.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_15.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_15 = uibutton(app.Tab27, 'push');
            app.InformacionbiodigestotresButton_15.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_15.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_28 = uiimage(app.Tab27);
            app.Image2_28.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.MaleenMundLabel = uilabel(app.Tab27);
            app.MaleenMundLabel.Text = '© Maleen Mund';
            app.SalirButton_16 = uibutton(app.Tab27, 'push');
            app.SalirButton_16.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_16.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_16.Text = 'Salir';
            app.Tab28 = uitab(app.TabGroup);
            app.Tab28.Title = 'Tab28';
            app.CarbonvegetalLabel = uilabel(app.Tab28);
            app.CarbonvegetalLabel.Text = 'Carbon vegetal ';
            app.Roble_13 = uiimage(app.Tab28);
            app.Roble_13.ImageSource = fullfile(pathToMLAPP, 'carbon.jpg');
            app.AcerpseudoplatanusButton_16 = uibutton(app.Tab28, 'push');
            app.AcerpseudoplatanusButton_16.ButtonPushedFcn = createCallbackFcn(app, @AcerpseudoplatanusButtonPushed2, true);
            app.AcerpseudoplatanusButton_16.Text = 'Acer pseudoplatanus';
            app.AbedulButton_16 = uibutton(app.Tab28, 'push');
            app.AbedulButton_16.ButtonPushedFcn = createCallbackFcn(app, @AbedulButtonPushed2, true);
            app.AbedulButton_16.Text = 'Abedul';
            app.HayaButton_16 = uibutton(app.Tab28, 'push');
            app.HayaButton_16.ButtonPushedFcn = createCallbackFcn(app, @HayaButtonPushed, true);
            app.HayaButton_16.Text = 'Haya';
            app.RobleButton_16 = uibutton(app.Tab28, 'push');
            app.RobleButton_16.ButtonPushedFcn = createCallbackFcn(app, @RobleButtonPushed, true);
            app.RobleButton_16.Text = 'Roble';
            app.QuercuscerrisButton_16 = uibutton(app.Tab28, 'push');
            app.QuercuscerrisButton_16.ButtonPushedFcn = createCallbackFcn(app, @QuercuscerrisButtonPushed, true);
            app.QuercuscerrisButton_16.Text = 'Quercus cerris';
            app.AlnusglutinosaButton_16 = uibutton(app.Tab28, 'push');
            app.AlnusglutinosaButton_16.ButtonPushedFcn = createCallbackFcn(app, @AlnusglutinosaButtonPushed, true);
            app.AlnusglutinosaButton_16.Text = 'Alnus glutinosa';
            app.AlnusincanaButton_16 = uibutton(app.Tab28, 'push');
            app.AlnusincanaButton_16.ButtonPushedFcn = createCallbackFcn(app, @AlnusincanaButtonPushed, true);
            app.AlnusincanaButton_16.Text = 'Alnus incana';
            app.FresnoButton_16 = uibutton(app.Tab28, 'push');
            app.FresnoButton_16.ButtonPushedFcn = createCallbackFcn(app, @FresnoButtonPushed, true);
            app.FresnoButton_16.Text = 'Fresno';
            app.CarpeButton_16 = uibutton(app.Tab28, 'push');
            app.CarpeButton_16.ButtonPushedFcn = createCallbackFcn(app, @CarpeButtonPushed, true);
            app.CarpeButton_16.Text = 'Carpe';
            app.OlmoButton_16 = uibutton(app.Tab28, 'push');
            app.OlmoButton_16.ButtonPushedFcn = createCallbackFcn(app, @OlmoButtonPushed, true);
            app.OlmoButton_16.Text = 'Olmo';
            app.SalixalbaButton_16 = uibutton(app.Tab28, 'push');
            app.SalixalbaButton_16.ButtonPushedFcn = createCallbackFcn(app, @SalixalbaButtonPushed, true);
            app.SalixalbaButton_16.Text = 'Salix alba';
            app.AbetorojoButton_16 = uibutton(app.Tab28, 'push');
            app.AbetorojoButton_16.ButtonPushedFcn = createCallbackFcn(app, @AbetorojoButtonPushed, true);
            app.AbetorojoButton_16.Text = 'Abeto rojo';
            app.PinosilvestreButton_16 = uibutton(app.Tab28, 'push');
            app.PinosilvestreButton_16.ButtonPushedFcn = createCallbackFcn(app, @PinosilvestreButtonPushed, true);
            app.PinosilvestreButton_16.Text = 'Pino silvestre';
            app.PinusstrobusButton_16 = uibutton(app.Tab28, 'push');
            app.PinusstrobusButton_16.ButtonPushedFcn = createCallbackFcn(app, @PinusstrobusButtonPushed, true);
            app.PinusstrobusButton_16.Text = 'Pinus strobus';
            app.PinabeteButton_16 = uibutton(app.Tab28, 'push');
            app.PinabeteButton_16.ButtonPushedFcn = createCallbackFcn(app, @PinabeteButtonPushed, true);
            app.PinabeteButton_16.Text = 'Pinabete';
            app.CarbonvegetalButton_16 = uibutton(app.Tab28, 'push');
            app.CarbonvegetalButton_16.ButtonPushedFcn = createCallbackFcn(app, @CarbonvegetalButtonPushed, true);
            app.CarbonvegetalButton_16.Text = 'Carbon vegetal ';
            app.InformacionbiodigestotresButton_16 = uibutton(app.Tab28, 'push');
            app.InformacionbiodigestotresButton_16.ButtonPushedFcn = createCallbackFcn(app, @InformacionbiodigestotresButtonPushed, true);
            app.InformacionbiodigestotresButton_16.Text = {'Informacion '; 'biodigestotres'};
            app.Image2_29 = uiimage(app.Tab28);
            app.Image2_29.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.STRONGlk7Label = uilabel(app.Tab28);
            app.STRONGlk7Label.Text = 'STRONGlk7';
            app.SalirButton_17 = uibutton(app.Tab28, 'push');
            app.SalirButton_17.ButtonPushedFcn = createCallbackFcn(app, @salirboton, true);
            app.SalirButton_17.Icon = fullfile(pathToMLAPP, 'Salir.png');
            app.SalirButton_17.Text = 'Salir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = informacion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end